classdef OPT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        LoadOPTsessionfileMenu          matlab.ui.container.Menu
        SaveasOPTsessionfileMenu        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        ClearallfieldsMenu              matlab.ui.container.Menu
        QuitappMenu                     matlab.ui.container.Menu
        ResultsMenu                     matlab.ui.container.Menu
        ClearandloadsavedmessagesMenu   matlab.ui.container.Menu
        SavedisplayedmessagesMenu       matlab.ui.container.Menu
        LoadsavedresultsMenu            matlab.ui.container.Menu
        ClearresultsMenu                matlab.ui.container.Menu
        SavedisplayedresultsMenu        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationwithexamplesMenu   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralPropellantDataTab        matlab.ui.container.Tab
        RunButton_1                     matlab.ui.control.Button
        FuelDataPanel                   matlab.ui.container.Panel
        Label_blocking_info_1           matlab.ui.control.Label
        beta                            matlab.ui.control.NumericEditField
        beta1Label                      matlab.ui.control.Label
        C_pr                            matlab.ui.control.NumericEditField
        C_prLabel                       matlab.ui.control.Label
        burn_expo                       matlab.ui.control.NumericEditField
        Burn_expoLabel                  matlab.ui.control.Label
        burn_coefficient                matlab.ui.control.NumericEditField
        EurncoefficientLabel            matlab.ui.control.Label
        propellant_density              matlab.ui.control.NumericEditField
        PropellantdensityLabel          matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        expected_relative_accuracy      matlab.ui.control.NumericEditField
        ExpectedAccuracyLabel           matlab.ui.control.Label
        factor_altitude                 matlab.ui.control.NumericEditField
        FactorforaltitudeLabel          matlab.ui.control.Label
        factor_landing_points           matlab.ui.control.NumericEditField
        Factorforlanding_pointsLabel    matlab.ui.control.Label
        session_title                   matlab.ui.control.EditField
        SimulationstitleLabel           matlab.ui.control.Label
        GeometryTypesTab                matlab.ui.container.Tab
        RunButton_2                     matlab.ui.control.Button
        TableofgeometriesPanel          matlab.ui.container.Panel
        UITableGeometries               matlab.ui.control.Table
        TabGroup2                       matlab.ui.container.TabGroup
        TubeTab                         matlab.ui.container.Tab
        DeletefromTableofgeometriesButton  matlab.ui.control.Button
        scaleofnormalsLabel             matlab.ui.control.Label
        scale_normals_1                 matlab.ui.control.NumericEditField
        transparency_surfaces_1         matlab.ui.control.NumericEditField
        transparencyofsurfacesLabel     matlab.ui.control.Label
        resolution_1                    matlab.ui.control.NumericEditField
        ResolutionLabel                 matlab.ui.control.Label
        diameter_outer_1                matlab.ui.control.NumericEditField
        diameterouterLabel              matlab.ui.control.Label
        length_cyl_1                    matlab.ui.control.NumericEditField
        lengthofcylinderLabel           matlab.ui.control.Label
        filename_1                      matlab.ui.control.EditField
        FilenameLabel_2                 matlab.ui.control.Label
        AddtoTableofgeometriesButton_1  matlab.ui.control.Button
        SaveSTLfileCheckBox             matlab.ui.control.CheckBox
        ViewgeometryCheckBox            matlab.ui.control.CheckBox
        OptimiseddimensionDropDown      matlab.ui.control.DropDown
        OptimiseddimensionDropDownLabel  matlab.ui.control.Label
        diameter_inner_1                matlab.ui.control.NumericEditField
        diameterinnerLabel              matlab.ui.control.Label
        MultiChannelTab                 matlab.ui.container.Tab
        DeletefromTableofgeometriesButton_2  matlab.ui.control.Button
        AddtoTableofgeometriesButton_2  matlab.ui.control.Button
        ScalefactorfornormalsLabel      matlab.ui.control.Label
        scale_normals_2                 matlab.ui.control.NumericEditField
        transparency_surfaces_2         matlab.ui.control.NumericEditField
        TransparencyofsurfacesLabel     matlab.ui.control.Label
        resolution_2                    matlab.ui.control.NumericEditField
        ResolutionLabel_2               matlab.ui.control.Label
        diameter_outer_2                matlab.ui.control.NumericEditField
        DiameterouterLabel              matlab.ui.control.Label
        length_cyl_2                    matlab.ui.control.NumericEditField
        lengthofcylinderLabel_2         matlab.ui.control.Label
        filename_2                      matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        SaveSTLfileCheckBox_2           matlab.ui.control.CheckBox
        ViewgeometryCheckBox_2          matlab.ui.control.CheckBox
        OptimiseddimensionDropDown_2    matlab.ui.control.DropDown
        OptimiseddimensionDropDown_2Label  matlab.ui.control.Label
        channel_ring_diameter_2         matlab.ui.control.NumericEditField
        RingchannelsdiameterLabel       matlab.ui.control.Label
        NumberofchannelsSpinner         matlab.ui.control.Spinner
        NumberofchannelsSpinnerLabel    matlab.ui.control.Label
        channel_diameter_2              matlab.ui.control.NumericEditField
        ChannelsdiameterLabel           matlab.ui.control.Label
        StarTab                         matlab.ui.container.Tab
        DeletefromTableofgeometriesButton_3  matlab.ui.control.Button
        AddtoTableofgeometriesButton_3  matlab.ui.control.Button
        ScalefactorfornormalsLabel_2    matlab.ui.control.Label
        scale_normals_3                 matlab.ui.control.NumericEditField
        transparency_surfaces_3         matlab.ui.control.NumericEditField
        TransparencyofsurfacesLabel_2   matlab.ui.control.Label
        resolution_3                    matlab.ui.control.NumericEditField
        ResolutionLabel_4               matlab.ui.control.Label
        diameter_outer_3                matlab.ui.control.NumericEditField
        DiameterouterLabel_2            matlab.ui.control.Label
        length_cyl_3                    matlab.ui.control.NumericEditField
        lengthofcylinderLabel_3         matlab.ui.control.Label
        filename_3                      matlab.ui.control.EditField
        FilenameLabel_3                 matlab.ui.control.Label
        SaveSTLfileCheckBox_3           matlab.ui.control.CheckBox
        ViewgeometryCheckBox_3          matlab.ui.control.CheckBox
        star_arm_angle_3                matlab.ui.control.NumericEditField
        stararmangleLabel               matlab.ui.control.Label
        NumberofstarsarmsSpinner        matlab.ui.control.Spinner
        NumberofstarsarmsSpinnerLabel   matlab.ui.control.Label
        star_outer_diameter_3           matlab.ui.control.NumericEditField
        starouterdiameterLabel          matlab.ui.control.Label
        OptimiseddimensionDropDown_3    matlab.ui.control.DropDown
        OptimiseddimensionDropDown_3Label  matlab.ui.control.Label
        FunctionChannelTab              matlab.ui.container.Tab
        DeletefromTableofgeometriesButton_4  matlab.ui.control.Button
        AddtoTableofgeometriesButton_4  matlab.ui.control.Button
        SaveSTLfileCheckBox_4           matlab.ui.control.CheckBox
        radial_function_4               matlab.ui.control.EditField
        RadialfunctionLabel             matlab.ui.control.Label
        OptimiseddimensionDropDown_4    matlab.ui.control.DropDown
        OptimiseddimensionDropDown_4Label  matlab.ui.control.Label
        ScalefactorfornormalsLabel_3    matlab.ui.control.Label
        scale_normals_4                 matlab.ui.control.NumericEditField
        transparency_surfaces_4         matlab.ui.control.NumericEditField
        TransparencyofsurfacesLabel_3   matlab.ui.control.Label
        resolution_4                    matlab.ui.control.NumericEditField
        ResolutionLabel_3               matlab.ui.control.Label
        diameter_outer_4                matlab.ui.control.NumericEditField
        DiameterouterLabel_3            matlab.ui.control.Label
        length_cyl_4                    matlab.ui.control.NumericEditField
        lengthofcylinderLabel_4         matlab.ui.control.Label
        filename_4                      matlab.ui.control.EditField
        FilenameLabel_4                 matlab.ui.control.Label
        ViewgeometryCheckBox_4          matlab.ui.control.CheckBox
        CEAMTab                         matlab.ui.container.Tab
        RunButton_3                     matlab.ui.control.Button
        DeletefromTableofreactantsforCEAMButton  matlab.ui.control.Button
        Label_blocking_info_2           matlab.ui.control.Label
        FormulaPanel                    matlab.ui.container.Panel
        DeletefromTableofelementsButton  matlab.ui.control.Button
        ElementDropDown                 matlab.ui.control.DropDown
        ElementDropDownLabel            matlab.ui.control.Label
        NumberofatomsSpinner            matlab.ui.control.Spinner
        NumberofatomsSpinnerLabel       matlab.ui.control.Label
        AddtoTableofelementsButton      matlab.ui.control.Button
        UITableElements                 matlab.ui.control.Table
        AddtoTableofreactantsforCEAMButton  matlab.ui.control.Button
        density                         matlab.ui.control.NumericEditField
        DensityLabel                    matlab.ui.control.Label
        density_unit                    matlab.ui.control.EditField
        DensityunitLabel                matlab.ui.control.Label
        enthalpy                        matlab.ui.control.NumericEditField
        EnthalpyLabel                   matlab.ui.control.Label
        enthalpy_unit                   matlab.ui.control.EditField
        EnthalpyunitLabel               matlab.ui.control.Label
        weight_percent                  matlab.ui.control.NumericEditField
        WeightpercentLabel              matlab.ui.control.Label
        name                            matlab.ui.control.EditField
        NameLabel                       matlab.ui.control.Label
        TableofreactantsforCEAMPanel    matlab.ui.container.Panel
        UITableReactants                matlab.ui.control.Table
        RPATRFSTab                      matlab.ui.container.Tab
        RunButton_4                     matlab.ui.control.Button
        RFSdataPanel                    matlab.ui.container.Panel
        margin_strict                   matlab.ui.control.CheckBox
        margin                          matlab.ui.control.NumericEditField
        TraininggroudceilLabel_2        matlab.ui.control.Label
        SelectwindprofilefileButton     matlab.ui.control.Button
        SelecttraininggroundfileButton  matlab.ui.control.Button
        SelectRFSsessionButton          matlab.ui.control.Button
        wind_profile_filepath           matlab.ui.control.EditField
        original_session_filepathEditField_2Label_2  matlab.ui.control.Label
        azimuth                         matlab.ui.control.NumericEditField
        AzimuthdegEditFieldLabel        matlab.ui.control.Label
        longitude                       matlab.ui.control.NumericEditField
        LongitudedegEditFieldLabel      matlab.ui.control.Label
        latitude                        matlab.ui.control.NumericEditField
        LatitudedegEditFieldLabel       matlab.ui.control.Label
        CreateRFSsessionfileinRFSGUIButton  matlab.ui.control.Button
        training_groud_filepath         matlab.ui.control.EditField
        TraininggroudfilepathLabel      matlab.ui.control.Label
        max_wind_speed                  matlab.ui.control.NumericEditField
        MaxwindspeedLabel               matlab.ui.control.Label
        original_session_filepathRFS    matlab.ui.control.EditField
        OriginalsessionfilepathLabel_2  matlab.ui.control.Label
        training_groud_ceil             matlab.ui.control.NumericEditField
        TraininggroudceilLabel          matlab.ui.control.Label
        UseazimuthfromsessionfileCheckBox  matlab.ui.control.CheckBox
        UsecoordinatesfromsessionfileCheckBox  matlab.ui.control.CheckBox
        RPATdata                        matlab.ui.container.Panel
        CreateRPATinputfileinRPATsGUIButton  matlab.ui.control.Button
        SelectRPATinputfileButton       matlab.ui.control.Button
        original_session_filepathRPAT   matlab.ui.control.EditField
        OriginalsessionfilepathLabel    matlab.ui.control.Label
        UsedatafromCEAMCheckBox         matlab.ui.control.CheckBox
        MessagesTab                     matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        ResultsTab                      matlab.ui.container.Tab
        UITableResults                  matlab.ui.control.Table
    end

    properties (Access = private)
        % Boolean which stores whether the program is working on an
        % existing session file
        fileLoaded
        % Name of the session file
        sessionFileName
        % Path of the session file
        sessionPath
        % Full path of the session file
        sessionFullFilename
        % Structure which stores the default parameters of a new simulation
        DefaultParameters
        % Boolean which stores whether the program was run by GUI
        run_by_GUI

    end

    methods (Access = private)

        function LoadSessionFile(app, event)
            [app.sessionFileName, app.sessionPath, ~] = uigetfile({'*.OPTsession',...
                'OPT Session Files (*.OPTsession)'; '*.*', 'All Files (*.*)'},...
                'Load session', './main/Input/Sessions/');
            app.sessionFullFilename = fullfile(app.sessionPath, app.sessionFileName);
            SessionParameters = jsondecode(fileread(app.sessionFullFilename));

            LoadData(app, event, SessionParameters);
            app.fileLoaded = true;
            figure(app.UIFigure);
        end


        function LoadData(app, event, SessionParameters)
            if SessionParameters.RPATdata.boolean_CEAM_data_usage
                app.beta.Value = SessionParameters.NonCEAMDataForRPAT.beta;
                app.C_pr.Value = SessionParameters.NonCEAMDataForRPAT.C_pr;
                app.burn_expo.Value = SessionParameters.NonCEAMDataForRPAT.burn_expo;
                app.burn_coefficient.Value = SessionParameters.NonCEAMDataForRPAT.burn_coefficient;
                app.propellant_density.Value = SessionParameters.NonCEAMDataForRPAT.propellant_density;
            else
                app.beta.Value = 0;
                app.C_pr.Value = 0;
                app.burn_expo.Value = 0;
                app.burn_coefficient.Value = 0;
                app.propellant_density.Value = 0;
            end
            app.expected_relative_accuracy.Value = SessionParameters.General.expected_relative_accuracy;
            app.factor_altitude.Value = SessionParameters.General.factor_altitude;
            app.factor_landing_points.Value = SessionParameters.General.factor_landing_points;
            app.session_title.Value = SessionParameters.General.session_title;

            app.UITableGeometries.Data = ReadGeometriesInput(app, SessionParameters);
            app.UITableGeometries.RowName = {1:height(app.UITableGeometries.Data)};

            if SessionParameters.RPATdata.boolean_CEAM_data_usage
                app.UITableReactants.Data = ReadCEAMInput(app, SessionParameters);
            else
                app.UITableReactants.Data = [];
            end
            % app.UITableReactants.RowName = {1:height(app.UITableReactants.Data)};

            app.original_session_filepathRFS.Value = SessionParameters.RFSdata.original_session_filepath;
            app.UseazimuthfromsessionfileCheckBox.Value = SessionParameters.RFSdata.boolean_azimuth_from_session;
            app.UsecoordinatesfromsessionfileCheckBox.Value = SessionParameters.RFSdata.boolean_coordinates_from_session;
            if isfield(SessionParameters.RFSdata,'max_wind_speed')
                app.max_wind_speed.Value = SessionParameters.RFSdata.max_wind_speed;
            else
                app.max_wind_speed.Value = 0;
            end
            if isfield(SessionParameters.RFSdata,'wind_profile_filepath')
                app.wind_profile_filepath.Value = SessionParameters.RFSdata.wind_profile_filepath;
            else
                app.wind_profile_filepath.Value  = '';
            end
            app.training_groud_filepath.Value = SessionParameters.RFSdata.training_groud_filepath;
            app.training_groud_ceil.Value = SessionParameters.RFSdata.training_groud_ceil;
            if isfield(SessionParameters.RFSdata,'margin')
                app.margin.Value = SessionParameters.RFSdata.margin;
            else
                app.margin.Value = 0;
            end
            if isfield(SessionParameters.RFSdata,'margin_strict')
                app.margin_strict.Value = SessionParameters.RFSdata.margin_strict;
            else
                app.margin_strict.Value = false;
            end
            app.original_session_filepathRPAT.Value = SessionParameters.RPATdata.original_session_filepath;
            app.UsedatafromCEAMCheckBox.Value = SessionParameters.RPATdata.boolean_CEAM_data_usage;
            if isfield(SessionParameters.RFSdata,'latitude')
                app.latitude.Value = SessionParameters.RFSdata.latitude;
            else
                app.latitude.Value = 0;
            end
            if isfield(SessionParameters.RFSdata,'longitude')
                app.longitude.Value = SessionParameters.RFSdata.longitude;
            else
                app.longitude.Value = 0;
            end
            if isfield(SessionParameters.RFSdata,'azimuth')
                app.azimuth.Value = SessionParameters.RFSdata.azimuth;
            else
                app.azimuth.Value = 0;
            end
            UsedatafromCEAMCheckBoxValueChanged(app,event);
            UseazimuthfromsessionfileCheckBoxValueChanged(app,event);
            UsecoordinatesfromsessionfileCheckBoxValueChanged(app,event);
        end


        function SaveSession(app, saveAs)
            if app.fileLoaded == false || saveAs == true
                [app.sessionFileName, app.sessionPath, ~] = uiputfile({'*.OPTsession',...
                    'OPT Session Files (*.OPTsession)'; '*.*', 'All Files (*.*)'},...
                    'Save session as', './main/Input/Sessions/');
                figure(app.UIFigure)
            end
            if app.sessionFileName == 0
                return
            end
            app.sessionFullFilename = fullfile(app.sessionPath, app.sessionFileName);
            fileID = fopen(app.sessionFullFilename, 'wt');
            SessionParameters = ReadData(app);
            fprintf(fileID, jsonencode(SessionParameters,"PrettyPrint",true));
            fclose(fileID);
            app.fileLoaded = true;
        end


        function SessionParameters = ReadData(app)
            SessionParameters.NonCEAMDataForRPAT.beta = app.beta.Value;
            SessionParameters.NonCEAMDataForRPAT.C_pr = app.C_pr.Value;
            SessionParameters.NonCEAMDataForRPAT.burn_expo = app.burn_expo.Value;
            SessionParameters.NonCEAMDataForRPAT.burn_coefficient = app.burn_coefficient.Value;
            SessionParameters.NonCEAMDataForRPAT.propellant_density = app.propellant_density.Value;
            SessionParameters.General.expected_relative_accuracy = app.expected_relative_accuracy.Value;
            SessionParameters.General.factor_altitude = app.factor_altitude.Value;
            SessionParameters.General.factor_landing_points = app.factor_landing_points.Value;
            SessionParameters.General.session_title = app.session_title.Value;


            SessionParameters.Geometry = ReadGeometriesTable(app);


            SessionParameters.RFSdata.original_session_filepath = app.original_session_filepathRFS.Value;
            SessionParameters.RFSdata.boolean_azimuth_from_session = app.UseazimuthfromsessionfileCheckBox.Value;
            if app.UseazimuthfromsessionfileCheckBox.Value == false
                SessionParameters.RFSdata.azimuth = app.azimuth.Value;
            end
            SessionParameters.RFSdata.boolean_coordinates_from_session = app.UsecoordinatesfromsessionfileCheckBox.Value;
            if app.UsecoordinatesfromsessionfileCheckBox.Value == false
                SessionParameters.RFSdata.latitude = app.latitude.Value;
                SessionParameters.RFSdata.longitude = app.longitude.Value;
            end
            if strcmp(app.wind_profile_filepath.Value,'')
                SessionParameters.RFSdata.max_wind_speed = app.max_wind_speed.Value;
            end
            if app.max_wind_speed.Value == 0
                SessionParameters.RFSdata.wind_profile_filepath = app.wind_profile_filepath.Value;
            end
            SessionParameters.RFSdata.training_groud_filepath = app.training_groud_filepath.Value;
            SessionParameters.RFSdata.training_groud_ceil = app.training_groud_ceil.Value;
            if app.margin.Value ~= 0
                SessionParameters.RFSdata.margin = app.margin.Value;
            end
            if app.margin.Value ~= 0
                SessionParameters.RFSdata.margin_strict = app.margin_strict.Value;
            end
            SessionParameters.RPATdata.original_session_filepath = app.original_session_filepathRPAT.Value;
            SessionParameters.RPATdata.boolean_CEAM_data_usage = app.UsedatafromCEAMCheckBox.Value;

            
            if SessionParameters.RPATdata.boolean_CEAM_data_usage
                SessionParameters.CEAMreactants = ReadCEAMTable(app);
            end
        end


        function AskToSave(app,quitting)
            selection = uiconfirm(app.UIFigure,...
                'Do you want to save changes to New Simulation?',...
                'Rocket Flight Simulation', 'Options', {'Save',...
                'Don''t Save', 'Cancel'}, 'icon', '');
            switch selection
                case 'Save'
                    SaveSession(app, false);
                    if quitting == true
                        delete(app);
                    else
                        LoadDefault(app);
                    end
                case 'Don''t Save'
                    if quitting == true
                        delete(app);
                    else
                        LoadDefault(app);
                    end
                case 'Cancel'
            end
        end


        function LoadDefault(app)
            app.beta.Value = 0;
            app.C_pr.Value = 0;
            app.burn_expo.Value = 0;
            app.burn_coefficient.Value = 0;
            app.propellant_density.Value = 0;
            
            app.expected_relative_accuracy.Value = 0;
            app.factor_altitude.Value = 0;
            app.factor_landing_points.Value = 0;
            app.session_title.Value = '';

            app.UITableGeometries.Data = [];
            app.filename_1.Value = "";
            app.length_cyl_1.Value = 0;
            app.diameter_outer_1.Value = 0;
            app.diameter_inner_1.Value = 0;
            app.resolution_1.Value = 0;
            app.ViewgeometryCheckBox.Value = true;
            app.transparency_surfaces_1.Value = 0;
            app.scale_normals_1.Value = 0;
            app.SaveSTLfileCheckBox.Value = true;
            app.OptimiseddimensionDropDown.Value = 'diameter_inner';
            app.filename_2.Value = "";
            app.length_cyl_2.Value = 0;
            app.diameter_outer_2.Value = 0;
            app.NumberofchannelsSpinner.Value = 0;
            app.channel_diameter_2.Value = 0;
            app.channel_ring_diameter_2.Value = 0;
            app.resolution_2.Value = 0;
            app.ViewgeometryCheckBox_2.Value = true;
            app.transparency_surfaces_2.Value = 0;
            app.scale_normals_2.Value = 0;
            app.SaveSTLfileCheckBox_2.Value = true;
            app.OptimiseddimensionDropDown_2.Value = 'channel_ring_diameter';
            app.filename_3.Value = "";
            app.length_cyl_3.Value = 0;
            app.diameter_outer_3.Value = 0;
            app.NumberofstarsarmsSpinner.Value = 0;
            app.star_outer_diameter_3.Value = 0;
            app.star_arm_angle_3.Value = 0;
            app.resolution_3.Value = 0;
            app.ViewgeometryCheckBox_3.Value = true;
            app.transparency_surfaces_3.Value = 0;
            app.scale_normals_3.Value = 0;
            app.SaveSTLfileCheckBox_3.Value = true;
            app.OptimiseddimensionDropDown_3.Value = 'star_outer_diameter';
            app.filename_4.Value = "";
            app.length_cyl_4.Value = 0;
            app.diameter_outer_4.Value = 0;
            app.radial_function_4.Value = "";
            app.resolution_4.Value = 0;
            app.ViewgeometryCheckBox_4.Value = true;
            app.transparency_surfaces_4.Value = 0;
            app.scale_normals_4.Value = 0;
            app.SaveSTLfileCheckBox_4.Value = true;
            app.OptimiseddimensionDropDown_4.Value = 'radial_function';

            app.name.Value = '';
            app.weight_percent.Value = 0;
            app.enthalpy.Value = 0;
            app.enthalpy_unit.Value = '';
            app.density.Value = 0;
            app.density_unit.Value = '';
            app.ElementDropDown.Value = 'H';
            app.NumberofatomsSpinner.Value = 0;
            app.UITableElements.Data = [];
            app.UITableReactants.Data = [];

            app.original_session_filepathRPAT.Value = '';
            app.UsedatafromCEAMCheckBox.Value = false;
            
            app.original_session_filepathRFS.Value = '';
            app.max_wind_speed.Value = 0;
            app.wind_profile_filepath.Value  = '';
            app.training_groud_filepath.Value = '';
            app.training_groud_ceil.Value = 0;
            app.margin = 0;
            app.margin_strict = false;
            app.UseazimuthfromsessionfileCheckBox.Value = false;
            app.UsecoordinatesfromsessionfileCheckBox.Value = false;
            app.latitude.Value = 0;
            app.longitude.Value = 0;
            app.azimuth.Value = 0;
        end


        function tableCEAM = ReadCEAMInput(app, SessionParameters)
            names = fieldnames(SessionParameters.CEAMreactants); %names as name is use as property
            tableCEAM = cell2table(names);
            subnames = fieldnames(SessionParameters.CEAMreactants.(names{1,1}));
            for j = 1:length(subnames)
                for i = 1:length(names)
                    if j~=2
                        temp{i,1} = SessionParameters.CEAMreactants.(names{i,1}).(subnames{j,1});

                    else
                        elements_names = fieldnames(SessionParameters.CEAMreactants.(names{i,1}).(subnames{2,1}));
                        elements_string = [];
                        for k = 1:length(elements_names)
                            elements_string = strcat(elements_string,"'",elements_names{k,1},"',", ...
                                num2str(SessionParameters.CEAMreactants.(names{i,1}).(subnames{2,1}).(elements_names{k,1})));
                            if k~=length(elements_names)
                                elements_string = strcat(elements_string,",");
                            end
                        end
                        temp{i,1} = elements_string;
                    end
                end
                tableCEAM = addvars(tableCEAM,temp);
                tableCEAM = renamevars(tableCEAM,'temp',(subnames{j,1}));
            end
        end

        function tableGeometries = ReadGeometriesInput(app, SessionParameters)
            number_of_geometries = length(SessionParameters.Geometry);
            % a trick to overcome problems with different indexing for one and multiple
            % geometries (the same geometry is added at end, but is not used as it lies
            % beyond final value of geometry_number) in an easy way
            if length(SessionParameters.Geometry) == 1
                SessionParameters.Geometry = {SessionParameters.Geometry; []};
            end
            all_names = {'type', 'filename', 'length_cyl', 'diameter_outer', 'resolution', 'viewGeometry', 'transparency_surfaces', 'scale_normals', 'optimised_dimension', ...
                'saveSTL', 'diameter_inner', 'channel_number', 'channel_diameter', 'channel_ring_diameter', 'star_arms_number', 'star_outer_diameter', 'star_arm_angle', ...
                'radial_function'};

            for i = 1:number_of_geometries
                if isfield(SessionParameters.Geometry{i,1},'view_parameters')
                    SessionParameters.Geometry{i,1}.viewGeometry = SessionParameters.Geometry{i,1}.view_parameters.viewGeometry;
                    SessionParameters.Geometry{i,1}.transparency_surfaces = SessionParameters.Geometry{i,1}.view_parameters.transparency_surfaces;
                    SessionParameters.Geometry{i,1}.scale_normals = SessionParameters.Geometry{i,1}.view_parameters.scale_normals;
                    SessionParameters.Geometry{i,1} = rmfield(SessionParameters.Geometry{i,1},'view_parameters');
                end
                if isempty(SessionParameters.Geometry{i, 1}.resolution)
                    SessionParameters.Geometry{i, 1}.resolution = NaN;
                end
                temp = struct2table(SessionParameters.Geometry{i,1});
                if ismember('view_parameters', temp.Properties.VariableNames)
                    temp = [temp, struct2table(temp.view_parameters)];
                    temp = removevars(temp, 'view_parameters');
                end
                unused_temp = array2table(NaN(size(setdiff(all_names,temp.Properties.VariableNames))));
                unused_temp.Properties.VariableNames = setdiff(all_names,temp.Properties.VariableNames);
                if i==1
                    tableGeometries = [temp, unused_temp];
                    for j = 1:length(tableGeometries.Properties.VariableNames)
                        if and(all(strcmp(tableGeometries.Properties.VariableNames{1,j},'radial_function')), all(isnan(tableGeometries.(tableGeometries.Properties.VariableNames{1,j}))))
                            tableGeometries.(tableGeometries.Properties.VariableNames{1,j}) = {tableGeometries.(tableGeometries.Properties.VariableNames{1,j})};
                        elseif isnumeric(tableGeometries.(tableGeometries.Properties.VariableNames{1,j}))
                            tableGeometries.(tableGeometries.Properties.VariableNames{1,j})=double(tableGeometries.(tableGeometries.Properties.VariableNames{1,j}));
                        end
                        if ischar(tableGeometries.(tableGeometries.Properties.VariableNames{1,j}))
                            tableGeometries.(tableGeometries.Properties.VariableNames{1,j})={tableGeometries.(tableGeometries.Properties.VariableNames{1,j})};
                        end
                    end
                else
                    temp = [temp, unused_temp];
                    for j = 1:length(temp.Properties.VariableNames)
                        if isnumeric(temp.(temp.Properties.VariableNames{1,j}))
                            temp.(temp.Properties.VariableNames{1,j})=double(temp.(temp.Properties.VariableNames{1,j}));
                        end
                        if ischar(temp.(temp.Properties.VariableNames{1,j}))
                            temp.(temp.Properties.VariableNames{1,j})={temp.(temp.Properties.VariableNames{1,j})};
                        end
                    end
                    tableGeometries = [tableGeometries;temp];
                end
            end
            % Proper order of data. First column is type and so on:
            tableGeometries = movevars(tableGeometries, 'filename', 'After', 'type');
            tableGeometries = movevars(tableGeometries, 'length_cyl', 'After', 'filename');
            tableGeometries = movevars(tableGeometries, 'diameter_outer', 'After', 'length_cyl');
            tableGeometries = movevars(tableGeometries, 'resolution', 'After', 'diameter_outer');
            tableGeometries = movevars(tableGeometries, 'viewGeometry', 'After', 'resolution');
            tableGeometries = movevars(tableGeometries, 'transparency_surfaces', 'After', 'viewGeometry');
            tableGeometries = movevars(tableGeometries, 'scale_normals', 'After', 'transparency_surfaces');
            tableGeometries = movevars(tableGeometries, 'optimised_dimension', 'After', 'scale_normals');
            tableGeometries = movevars(tableGeometries, 'saveSTL', 'After', 'optimised_dimension');
            tableGeometries = movevars(tableGeometries, 'diameter_inner', 'After', 'saveSTL');
            tableGeometries = movevars(tableGeometries, 'channel_number', 'After', 'diameter_inner');
            tableGeometries = movevars(tableGeometries, 'channel_diameter', 'After', 'channel_number');
            tableGeometries = movevars(tableGeometries, 'channel_ring_diameter', 'After', 'channel_diameter');
            tableGeometries = movevars(tableGeometries, 'star_arms_number', 'After', 'channel_ring_diameter');
            tableGeometries = movevars(tableGeometries, 'star_outer_diameter', 'After', 'star_arms_number');
            tableGeometries = movevars(tableGeometries, 'star_arm_angle', 'After', 'star_outer_diameter');
            tableGeometries = movevars(tableGeometries, 'radial_function', 'After', 'star_arm_angle');
        end

        function StructureGeometry = ReadGeometriesTable(app)
            for i = 1:height(app.UITableGeometries.Data)
                if strcmp(app.UITableGeometries.Data.type(i),'Cylinder')
                    StructureGeometry{i,1}.type = app.UITableGeometries.Data.type{i,1};
                    StructureGeometry{i,1}.filename = app.UITableGeometries.Data.filename{i,1};
                    StructureGeometry{i,1}.length_cyl = app.UITableGeometries.Data.length_cyl(i);
                    StructureGeometry{i,1}.diameter_outer = app.UITableGeometries.Data.diameter_outer(i);
                    StructureGeometry{i,1}.resolution = app.UITableGeometries.Data.resolution(i);
                    StructureGeometry{i,1}.view_parameters.viewGeometry = app.UITableGeometries.Data.viewGeometry{i,1};
                    StructureGeometry{i,1}.view_parameters.transparency_surfaces = app.UITableGeometries.Data.transparency_surfaces(i);
                    StructureGeometry{i,1}.view_parameters.scale_normals = app.UITableGeometries.Data.scale_normals(i);
                    StructureGeometry{i,1}.optimised_dimension = app.UITableGeometries.Data.optimised_dimension{i,1};
                    StructureGeometry{i,1}.saveSTL = app.UITableGeometries.Data.saveSTL{i,1};
                elseif strcmp(app.UITableGeometries.Data.type(i),'Tube')
                    StructureGeometry{i,1}.type = app.UITableGeometries.Data.type{i,1};
                    StructureGeometry{i,1}.filename = app.UITableGeometries.Data.filename{i,1};
                    StructureGeometry{i,1}.length_cyl = app.UITableGeometries.Data.length_cyl(i);
                    StructureGeometry{i,1}.diameter_outer = app.UITableGeometries.Data.diameter_outer(i);
                    StructureGeometry{i,1}.diameter_inner = app.UITableGeometries.Data.diameter_inner(i);
                    StructureGeometry{i,1}.resolution = app.UITableGeometries.Data.resolution(i);
                    StructureGeometry{i,1}.view_parameters.viewGeometry = app.UITableGeometries.Data.viewGeometry{i,1};
                    StructureGeometry{i,1}.view_parameters.transparency_surfaces = app.UITableGeometries.Data.transparency_surfaces(i);
                    StructureGeometry{i,1}.view_parameters.scale_normals = app.UITableGeometries.Data.scale_normals(i);
                    StructureGeometry{i,1}.optimised_dimension = app.UITableGeometries.Data.optimised_dimension{i,1};
                    StructureGeometry{i,1}.saveSTL = app.UITableGeometries.Data.saveSTL{i,1};
                elseif strcmp(app.UITableGeometries.Data.type(i),'MultiChannel')
                    StructureGeometry{i,1}.type = app.UITableGeometries.Data.type{i,1};
                    StructureGeometry{i,1}.filename = app.UITableGeometries.Data.filename{i,1};
                    StructureGeometry{i,1}.length_cyl = app.UITableGeometries.Data.length_cyl(i);
                    StructureGeometry{i,1}.diameter_outer = app.UITableGeometries.Data.diameter_outer(i);
                    StructureGeometry{i,1}.channel_number = app.UITableGeometries.Data.channel_number(i);
                    StructureGeometry{i,1}.channel_diameter = app.UITableGeometries.Data.channel_diameter(i);
                    StructureGeometry{i,1}.channel_ring_diameter = app.UITableGeometries.Data.channel_ring_diameter(i);
                    StructureGeometry{i,1}.resolution = app.UITableGeometries.Data.resolution(i);
                    StructureGeometry{i,1}.view_parameters.viewGeometry = app.UITableGeometries.Data.viewGeometry{i,1};
                    StructureGeometry{i,1}.view_parameters.transparency_surfaces = app.UITableGeometries.Data.transparency_surfaces(i);
                    StructureGeometry{i,1}.view_parameters.scale_normals = app.UITableGeometries.Data.scale_normals(i);
                    StructureGeometry{i,1}.optimised_dimension = app.UITableGeometries.Data.optimised_dimension{i,1};
                    StructureGeometry{i,1}.saveSTL = app.UITableGeometries.Data.saveSTL{i,1};
                elseif strcmp(app.UITableGeometries.Data.type(i),'Star')
                    StructureGeometry{i,1}.type = app.UITableGeometries.Data.type{i,1};
                    StructureGeometry{i,1}.filename = app.UITableGeometries.Data.filename{i,1};
                    StructureGeometry{i,1}.length_cyl = app.UITableGeometries.Data.length_cyl(i);
                    StructureGeometry{i,1}.diameter_outer = app.UITableGeometries.Data.diameter_outer(i);
                    StructureGeometry{i,1}.star_arms_number = app.UITableGeometries.Data.star_arms_number(i);
                    StructureGeometry{i,1}.star_outer_diameter = app.UITableGeometries.Data.star_outer_diameter(i);
                    StructureGeometry{i,1}.star_arm_angle = app.UITableGeometries.Data.star_arm_angle(i);
                    StructureGeometry{i,1}.resolution = app.UITableGeometries.Data.resolution(i);
                    StructureGeometry{i,1}.view_parameters.viewGeometry = app.UITableGeometries.Data.viewGeometry{i,1};
                    StructureGeometry{i,1}.view_parameters.transparency_surfaces = app.UITableGeometries.Data.transparency_surfaces(i);
                    StructureGeometry{i,1}.view_parameters.scale_normals = app.UITableGeometries.Data.scale_normals(i);
                    StructureGeometry{i,1}.optimised_dimension = app.UITableGeometries.Data.optimised_dimension{i,1};
                    StructureGeometry{i,1}.saveSTL = app.UITableGeometries.Data.saveSTL{i,1};
                elseif strcmp(app.UITableGeometries.Data.type(i),'FunctionChannel')
                    StructureGeometry{i,1}.type = app.UITableGeometries.Data.type{i,1};
                    StructureGeometry{i,1}.filename = app.UITableGeometries.Data.filename{i,1};
                    StructureGeometry{i,1}.length_cyl = app.UITableGeometries.Data.length_cyl(i);
                    StructureGeometry{i,1}.diameter_outer = app.UITableGeometries.Data.diameter_outer(i);
                    StructureGeometry{i,1}.radial_function = app.UITableGeometries.Data.radial_function{i,1};
                    StructureGeometry{i,1}.resolution = app.UITableGeometries.Data.resolution(i);
                    StructureGeometry{i,1}.view_parameters.viewGeometry = app.UITableGeometries.Data.viewGeometry{i,1};
                    StructureGeometry{i,1}.view_parameters.transparency_surfaces = app.UITableGeometries.Data.transparency_surfaces(i);
                    StructureGeometry{i,1}.view_parameters.scale_normals = app.UITableGeometries.Data.scale_normals(i);
                    StructureGeometry{i,1}.optimised_dimension = app.UITableGeometries.Data.optimised_dimension{i,1};
                    StructureGeometry{i,1}.saveSTL = app.UITableGeometries.Data.saveSTL{i,1};
                end
            end

        end

        function StructureCEAMreactants = ReadCEAMTable(app)
            names = app.UITableReactants.Data.names;
            for i = 1:height(app.UITableReactants.Data)
                StructureCEAMreactants.(names{i,1}).weight_percent = app.UITableReactants.Data.weight_percent{i,1};

                temp_formula = app.UITableReactants.Data.formula{i,1};
                temp_formula = temp_formula{1,1};
                found_apostrophes = strfind(temp_formula,'''');
                found_commas = [strfind(temp_formula,','), strlength(temp_formula)+1];
                element_names = string(zeros(length(found_apostrophes)/2,1));
                element_numbers = string(zeros(length(found_commas)/2,1));
                temp_formula = convertStringsToChars(temp_formula);
                for j = 1:length(found_apostrophes)/2
                    element_names(j) = convertCharsToStrings(temp_formula((found_apostrophes(2*j-1)+1):(found_apostrophes(2*j)-1)));
                end
                for j = 1:length(found_apostrophes)/2
                    element_numbers(j) = convertCharsToStrings(temp_formula((found_commas(2*j-1)+1):(found_commas(2*j)-1)));
                end
                element_numbers = str2double(element_numbers);
                for j = 1:length(found_apostrophes)/2
                    StructureCEAMreactants.(names{i,1}).formula.(element_names(j)) = element_numbers(j);
                end

                StructureCEAMreactants.(names{i,1}).enthalpy = app.UITableReactants.Data.enthalpy{i,1};
                StructureCEAMreactants.(names{i,1}).enthalpy_unit = app.UITableReactants.Data.enthalpy_unit{i,1};
                StructureCEAMreactants.(names{i,1}).density = app.UITableReactants.Data.density{i,1};
                StructureCEAMreactants.(names{i,1}).density_unit = app.UITableReactants.Data.density_unit{i,1};
            end
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadOPTsessionfileMenu
        function LoadOPTsessionfileMenuSelected(app, event)
            LoadSessionFile(app, event);
        end

        % Menu selected function: SaveasOPTsessionfileMenu
        function SaveasOPTsessionfileMenuSelected(app, event)
            SaveSession(app, false);
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            SaveSession(app, true);
        end

        % Button pushed function: AddtoTableofgeometriesButton_1
        function AddtoTableofgeometriesButton_1Pushed(app, event)
            type = {'Tube'};
            filename = {app.filename_1.Value};
            length_cyl = app.length_cyl_1.Value;
            diameter_outer = app.diameter_outer_1.Value;
            resolution = app.resolution_1.Value;
            if app.ViewgeometryCheckBox.Value == true
                viewGeometry = {'yes'};
            else
                viewGeometry = {'no'};
            end
            transparency_surfaces = app.transparency_surfaces_1.Value;
            scale_normals = app.scale_normals_1.Value;
            optimised_dimension = {app.OptimiseddimensionDropDown.Value};
            if app.SaveSTLfileCheckBox.Value == true
                saveSTL = {'yes'};
            else
                saveSTL = {'no'};
            end
            diameter_inner = app.diameter_inner_1.Value;
            channel_number = NaN;
            channel_diameter = NaN;
            channel_ring_diameter = NaN;
            star_arms_number = NaN;
            star_outer_diameter = NaN;
            star_arm_angle = NaN;
            radial_function = NaN;
            if height(app.UITableGeometries.Data) == 0
                app.UITableGeometries.Data = table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function);
            else
                app.UITableGeometries.Data = [app.UITableGeometries.Data; ...
                    table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function)];
            end
            app.UITableGeometries.RowName = {1:height(app.UITableGeometries.Data)};
        end

        % Button pushed function: AddtoTableofgeometriesButton_2
        function AddtoTableofgeometriesButton_2Pushed(app, event)
            type = {'MultiChannel'};
            filename = {app.filename_2.Value};
            length_cyl = app.length_cyl_2.Value;
            diameter_outer = app.diameter_outer_2.Value;
            resolution = app.resolution_2.Value;
            if app.ViewgeometryCheckBox_2.Value == true
                viewGeometry = {'yes'};
            else
                viewGeometry = {'no'};
            end
            transparency_surfaces = app.transparency_surfaces_2.Value;
            scale_normals = app.scale_normals_2.Value;
            optimised_dimension = {app.OptimiseddimensionDropDown_2.Value};
            if app.SaveSTLfileCheckBox_2.Value == true
                saveSTL = {'yes'};
            else
                saveSTL = {'no'};
            end
            diameter_inner = NaN;
            channel_number = app.NumberofchannelsSpinner.Value;
            channel_diameter = app.channel_diameter_2.Value;
            channel_ring_diameter = app.channel_ring_diameter_2.Value;
            star_arms_number = NaN;
            star_outer_diameter = NaN;
            star_arm_angle = NaN;
            radial_function = NaN;
            if height(app.UITableGeometries.Data) == 0
                app.UITableGeometries.Data = table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function);
            else
                app.UITableGeometries.Data = [app.UITableGeometries.Data; ...
                    table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function)];
            end
            app.UITableGeometries.RowName = {1:height(app.UITableGeometries.Data)};
        end

        % Button pushed function: AddtoTableofgeometriesButton_3
        function AddtoTableofgeometriesButton_3Pushed(app, event)
            type = {'Star'};
            filename = {app.filename_3.Value};
            length_cyl = app.length_cyl_3.Value;
            diameter_outer = app.diameter_outer_3.Value;
            resolution = app.resolution_3.Value;
            if app.ViewgeometryCheckBox_3.Value == true
                viewGeometry = {'yes'};
            else
                viewGeometry = {'no'};
            end
            transparency_surfaces = app.transparency_surfaces_3.Value;
            scale_normals = app.scale_normals_3.Value;
            optimised_dimension = {app.OptimiseddimensionDropDown_3.Value};
            if app.SaveSTLfileCheckBox_3.Value == true
                saveSTL = {'yes'};
            else
                saveSTL = {'no'};
            end
            diameter_inner = NaN;
            channel_number = NaN;
            channel_diameter = NaN;
            channel_ring_diameter = NaN;
            star_arms_number = app.NumberofstarsarmsSpinner.Value;
            star_outer_diameter = app.star_outer_diameter_3.Value;
            star_arm_angle = app.star_arm_angle_3.Value;
            radial_function = NaN;
            if height(app.UITableGeometries.Data) == 0
                app.UITableGeometries.Data = table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function);
            else
                app.UITableGeometries.Data = [app.UITableGeometries.Data; ...
                    table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function)];
            end
            app.UITableGeometries.RowName = {1:height(app.UITableGeometries.Data)};
        end

        % Button pushed function: AddtoTableofgeometriesButton_4
        function AddtoTableofgeometriesButton_4Pushed(app, event)
            type = {'FunctionChannel'};
            filename = {app.filename_4.Value};
            length_cyl = app.length_cyl_4.Value;
            diameter_outer = app.diameter_outer_4.Value;
            resolution = app.resolution_4.Value;
            if app.ViewgeometryCheckBox_4.Value == true
                viewGeometry = {'yes'};
            else
                viewGeometry = {'no'};
            end
            transparency_surfaces = app.transparency_surfaces_4.Value;
            scale_normals = app.scale_normals_4.Value;
            optimised_dimension = {app.OptimiseddimensionDropDown_4.Value};
            if app.SaveSTLfileCheckBox_4.Value == true
                saveSTL = {'yes'};
            else
                saveSTL = {'no'};
            end
            diameter_inner = NaN;
            channel_number = NaN;
            channel_diameter = NaN;
            channel_ring_diameter = NaN;
            star_arms_number = NaN;
            star_outer_diameter = NaN;
            star_arm_angle = NaN;
            radial_function = {app.radial_function_4.Value};
            if height(app.UITableGeometries.Data) == 0
                app.UITableGeometries.Data = table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function);
            else
                app.UITableGeometries.Data = [app.UITableGeometries.Data; ...
                    table(type,filename,length_cyl,diameter_outer,resolution, ...
                    viewGeometry,transparency_surfaces,scale_normals,optimised_dimension,saveSTL, ...
                    diameter_inner,channel_number,channel_diameter,channel_ring_diameter,star_arms_number, ...
                    star_outer_diameter,star_arm_angle,radial_function)];
            end
            app.UITableGeometries.RowName = {1:height(app.UITableGeometries.Data)};
        end

        % Button pushed function: AddtoTableofelementsButton
        function AddtoTableofelementsButtonPushed(app, event)
            element = {app.ElementDropDown.Value};
            numberofatoms = app.NumberofatomsSpinner.Value;
            if height(app.UITableElements.Data) == 0
                app.UITableElements.Data = table(element,numberofatoms);
            else
                app.UITableElements.Data = [app.UITableElements.Data; ...
                    table(element,numberofatoms)];
            end
            % app.UITableElements.RowName = {1:height(app.UITableElements.Data)};
        end

        % Button pushed function: AddtoTableofreactantsforCEAMButton
        function AddtoTableofreactantsforCEAMButtonPushed(app, event)
            names = {app.name.Value};
            weight_percent = app.weight_percent.Value;
            enthalpy = app.enthalpy.Value;
            enthalpy_unit = {app.enthalpy_unit.Value};
            density = app.density.Value;
            density_unit = {app.density_unit.Value};
            elements_names = app.UITableElements.Data.element;
            elements_numbers = app.UITableElements.Data.numberofatoms;
            formula = [];
            for k = 1:length(elements_names)
                formula = strcat(formula,"'",elements_names{k,1},"',", ...
                    num2str(elements_numbers(k)));
                if k~=length(elements_names)
                    formula = strcat(formula,",");
                end
            end
            formula = {formula};
            if height(app.UITableReactants.Data) == 0
                app.UITableReactants.Data = table(names,weight_percent,formula, ...
                    enthalpy,enthalpy_unit,density,density_unit);
            else
                app.UITableReactants.Data = [app.UITableReactants.Data; ...
                    table(names,weight_percent,formula, ...
                    enthalpy,enthalpy_unit,density,density_unit)];
            end
            app.UITableReactants.RowName = {1:height(app.UITableReactants.Data)};
        end

        % Button pushed function: RunButton_1, RunButton_2, RunButton_3, 
        % ...and 1 other component
        function RunButton_1Pushed(app, event)
            Session_Parameters = ReadData(app);
            app.run_by_GUI = true;
            MainOPT(Session_Parameters, app.run_by_GUI, app);
        end

        % Value changed function: UsedatafromCEAMCheckBox
        function UsedatafromCEAMCheckBoxValueChanged(app, event)
            if app.UsedatafromCEAMCheckBox.Value == false
                % app.FuelDataPanel.BackgroundColor = [0.49,0.49,0.49];
                app.FuelDataPanel.Enable = "off";
                app.beta.Enable = "off";
                app.C_pr.Enable = "off";
                app.burn_expo.Enable = "off";
                app.burn_coefficient.Enable = "off";
                app.propellant_density.Enable = "off";

                app.AddtoTableofreactantsforCEAMButton.Enable = "off";
                app.DeletefromTableofreactantsforCEAMButton.Enable = "off";
                app.name.Enable = "off";
                app.weight_percent.Enable = "off";
                app.enthalpy.Enable = "off";
                app.enthalpy_unit.Enable = "off";
                app.density.Enable = "off";
                app.density_unit.Enable = "off";
                app.NameLabel.Enable = "off";
                app.WeightpercentLabel.Enable = "off";
                app.EnthalpyLabel.Enable = "off";
                app.EnthalpyunitLabel.Enable = "off";
                app.DensityLabel.Enable = "off";
                app.DensityunitLabel.Enable = "off";
                app.FormulaPanel.Enable = "off";
                app.AddtoTableofelementsButton.Enable = "off";
                app.DeletefromTableofelementsButton.Enable = "off";
                app.ElementDropDown.Enable = "off";
                app.NumberofatomsSpinner.Enable = "off";
                app.UITableElements.Enable = "off";
                app.TableofreactantsforCEAMPanel.Enable = "off";
                app.UITableReactants.Enable = "off";
            else
                % app.FuelDataPanel.BackgroundColor = [0.94,0.94,0.94];
                app.FuelDataPanel.Enable = "on";
                app.beta.Enable = "on";
                app.C_pr.Enable = "on";
                app.burn_expo.Enable = "on";
                app.burn_coefficient.Enable = "on";
                app.propellant_density.Enable = "on";

                app.AddtoTableofreactantsforCEAMButton.Enable = "on";
                app.DeletefromTableofreactantsforCEAMButton.Enable = "on";
                app.name.Enable = "on";
                app.weight_percent.Enable = "on";
                app.enthalpy.Enable = "on";
                app.enthalpy_unit.Enable = "on";
                app.density.Enable = "on";
                app.density_unit.Enable = "on";
                app.NameLabel.Enable = "on";
                app.WeightpercentLabel.Enable = "on";
                app.EnthalpyLabel.Enable = "on";
                app.EnthalpyunitLabel.Enable = "on";
                app.DensityLabel.Enable = "on";
                app.DensityunitLabel.Enable = "on";
                app.FormulaPanel.Enable = "on";
                app.AddtoTableofelementsButton.Enable = "on";
                app.DeletefromTableofelementsButton.Enable = "on";
                app.ElementDropDown.Enable = "on";
                app.NumberofatomsSpinner.Enable = "on";
                app.UITableElements.Enable = "on";
                app.TableofreactantsforCEAMPanel.Enable = "on";
                app.UITableReactants.Enable = "on";
            end
        end

        % Value changed function: UsecoordinatesfromsessionfileCheckBox
        function UsecoordinatesfromsessionfileCheckBoxValueChanged(app, event)
            if app.UsecoordinatesfromsessionfileCheckBox.Value == false
                app.longitude.Enable = "on";
                app.LongitudedegEditFieldLabel.Enable = "on";
                app.latitude.Enable = "on";
                app.LatitudedegEditFieldLabel.Enable = "on";
            else
                app.longitude.Enable = "off";
                app.LongitudedegEditFieldLabel.Enable = "off";
                app.latitude.Enable = "off";
                app.LatitudedegEditFieldLabel.Enable = "off";
            end
        end

        % Value changed function: UseazimuthfromsessionfileCheckBox
        function UseazimuthfromsessionfileCheckBoxValueChanged(app, event)
            if app.UseazimuthfromsessionfileCheckBox.Value == false
                app.azimuth.Enable = "on";
                app.AzimuthdegEditFieldLabel.Enable = "on";
            else
                app.azimuth.Enable = "off";
                app.AzimuthdegEditFieldLabel.Enable = "off";
            end
        end

        % Value changed function: max_wind_speed
        function max_wind_speedValueChanged(app, event)
            if app.max_wind_speed.Value == 0
                app.wind_profile_filepath.Enable = "on";
                app.original_session_filepathEditField_2Label_2.Enable = "on";
            else
                app.wind_profile_filepath.Enable = "off";
                app.original_session_filepathEditField_2Label_2.Enable = "off";
            end
        end

        % Value changed function: wind_profile_filepath
        function wind_profile_filepathValueChanged(app, event)
            if strcmp(app.wind_profile_filepath.Value,'')
                app.max_wind_speed.Enable = "on";
                app.MaxwindspeedLabel.Enable = "on";
            else
                app.max_wind_speed.Enable = "off";
                app.MaxwindspeedLabel.Enable = "off";
            end
        end

        % Button pushed function: DeletefromTableofgeometriesButton, 
        % ...and 3 other components
        function DeletefromTableofgeometriesButtonPushed(app, event)
            if(~isempty(app.UITableGeometries.UserData))
                if(~isempty(app.UITableGeometries.UserData.indices))
                    rows = app.UITableGeometries.UserData.indices(:,1);
                    rows = unique(rows);
                    rows = sort(rows);
                    for i = 1:length(rows)
                        app.UITableGeometries.Data(rows(length(rows)+1-i), :) = [];
                    end
                end
            end
            % app.UITableGeometries.RowName = {1:height(app.UITableGeometries.Data)};
        end

        % Callback function: UITableGeometries, UITableGeometries
        function UITableGeometriesCellSelection(app, event)
            app.UITableGeometries.UserData.indices = event.Indices;
        end

        % Button pushed function: DeletefromTableofelementsButton
        function DeletefromTableofelementsButtonPushed(app, event)
            if(~isempty(app.UITableElements.UserData))
                if(~isempty(app.UITableElements.UserData.indices))
                    rows = app.UITableElements.UserData.indices(:,1);
                    rows = unique(rows);
                    rows = sort(rows);
                    for i = 1:length(rows)
                        app.UITableElements.Data(rows(length(rows)+1-i), :) = [];
                    end
                end
            end
            % app.UITableElements.RowName = {1:height(app.UITableElements.Data)};
        end

        % Cell selection callback: UITableElements
        function UITableElementsCellSelection(app, event)
            app.UITableElements.UserData.indices = event.Indices;
        end

        % Cell selection callback: UITableReactants
        function UITableReactantsCellSelection(app, event)
            app.UITableReactants.UserData.indices = event.Indices;
        end

        % Button pushed function: DeletefromTableofreactantsforCEAMButton
        function DeletefromTableofreactantsforCEAMButtonPushed(app, event)
            if(~isempty(app.UITableReactants.UserData))
                if(~isempty(app.UITableReactants.UserData.indices))
                    rows = app.UITableReactants.UserData.indices(:,1);
                    rows = unique(rows);
                    rows = sort(rows);
                    for i = 1:length(rows)
                        app.UITableReactants.Data(rows(length(rows)+1-i), :) = [];
                    end
                end
            end
            % app.UITableReactants.RowName = {1:height(app.UITableReactants.Data)};
        end

        % Button pushed function: SelectRPATinputfileButton
        function SelectRPATinputfileButtonPushed(app, event)
            [RPATsessionFileName, RPATsessionPath, ~] = uigetfile({'*.m',...
                'MATLAB Files (*.m)'; '*.RPATsession',...
                'RPAT Session Files (*.RPATsession)'; '*.mat',...
                'MATLAB Files (*.mat)'; '*.*', 'All Files (*.*)'},...
                'Load session', './main/Input/RPATsessions/');
            app.original_session_filepathRPAT.Value = fullfile(RPATsessionPath, RPATsessionFileName);
        end

        % Button pushed function: CreateRPATinputfileinRPATsGUIButton
        function CreateRPATinputfileinRPATsGUIButtonPushed(app, event)
            run('..\rpat\Source\main\RPAT_2_0.mlapp');
        end

        % Button pushed function: CreateRFSsessionfileinRFSGUIButton
        function CreateRFSsessionfileinRFSGUIButtonPushed(app, event)
            run('..\symulacja-lotu\Source\RFS_GUI.mlapp');
        end

        % Button pushed function: SelectRFSsessionButton
        function SelectRFSsessionButtonPushed(app, event)
            [RFSsessionFileName, RFSsessionPath, ~] = uigetfile({'*.RFSsession',...
                'RFS Session Files (*.RFSsession)'; '*.*', 'All Files (*.*)'},...
                'Load session file', './main/Input/RFS/Sessions/');
            app.original_session_filepathRFS.Value = fullfile(RFSsessionPath, RFSsessionFileName);
        end

        % Button pushed function: SelecttraininggroundfileButton
        function SelecttraininggroundfileButtonPushed(app, event)
            [TrainingGroundName, TrainingGroundPath, ~] = uigetfile({'*.txt',...
                'TXT Files (*.txt)'; '*.*', 'All Files (*.*)'},...
                'Load training ground file', './main/Input/TrainingGrounds/');
            app.training_groud_filepath.Value = fullfile(TrainingGroundPath, TrainingGroundName);
        end

        % Button pushed function: SelectwindprofilefileButton
        function SelectwindprofilefileButtonPushed(app, event)
            [RFSwindFileName, RFSwindPath, ~] = uigetfile({'*.RFSwind',...
                'RFSWind Profile Files (*.RFSwind)'; '*.*', 'All Files (*.*)'},...
                'Load wind profile file', './main/Input/RFS/Wind/');
            app.wind_profile_filepath.Value = fullfile(RFSwindPath, RFSwindFileName);
        end

        % Menu selected function: ClearallfieldsMenu
        function ClearallfieldsMenuSelected(app, event)
            AskToSave(app,false);
        end

        % Menu selected function: QuitappMenu
        function QuitappMenuSelected(app, event)
            AskToSave(app,true);
        end

        % Menu selected function: LoadsavedresultsMenu
        function LoadsavedresultsMenuSelected(app, event)
            [ResultsFileName, ResultsPath, ~] = uigetfile({'*.mat',...
                'MATLAB Files (*.mat)'; '*.*', 'All Files (*.*)'},...
                'Load saved results', './');
            load(fullfile(ResultsPath, ResultsFileName),'ResultsForGUITable');
            if height(app.UITableResults.Data) == 0
                app.UITableResults.Data = ResultsForGUITable;
            else
                app.UITableResults.Data = [app.UITableResults.Data; ...
                    ResultsForGUITable];
            end
            figure(app.UIFigure)
        end

        % Menu selected function: ClearresultsMenu
        function ClearresultsMenuSelected(app, event)
            app.UITableResults.Data = [];
        end

        % Menu selected function: DocumentationwithexamplesMenu
        function DocumentationwithexamplesMenuSelected(app, event)
            open('Praca_dyplomowa_inzynierska_Romanowski_Michal_05_01.pdf');
        end

        % Menu selected function: SavedisplayedresultsMenu
        function SavedisplayedresultsMenuSelected(app, event)
            [ResultsFileName, ResultsPath, ~] = uiputfile({'*.mat',...
                'MATLAB Files (*.mat)'; '*.*', 'All Files (*.*)'},...
                'Save session as', './');
            figure(app.UIFigure)
            if ResultsPath == 0
                return
            end
            ResultsForGUITable = app.UITableResults.Data;
            ResultsForGUITable.Properties.VariableNames = ["Date and time","Geometry type","Name","Optimised dimension","Optimised dimension final value","Final geometry path","Final geoplot path"];
            save(fullfile(ResultsPath, ResultsFileName),'ResultsForGUITable');
        end

        % Menu selected function: ClearandloadsavedmessagesMenu
        function ClearandloadsavedmessagesMenuSelected(app, event)
            [MessagesFileName, MessagesPath, ~] = uigetfile({'*.mat',...
                'MATLAB Files (*.mat)'; '*.*', 'All Files (*.*)'},...
                'Load saved results', './');
            load(fullfile(MessagesPath, MessagesFileName),'FinalHtml');
            app.HTML.HTMLSource = FinalHtml;
            figure(app.UIFigure)
        end

        % Menu selected function: SavedisplayedmessagesMenu
        function SavedisplayedmessagesMenuSelected(app, event)
            [MessagesFileName, MessagesPath, ~] = uiputfile({'*.mat',...
                'MATLAB Files (*.mat)'; '*.*', 'All Files (*.*)'},...
                'Save session as', './');
            figure(app.UIFigure)
            if MessagesPath == 0
                return
            end
            FinalHtml = app.HTML.HTMLSource;
            save(fullfile(MessagesPath, MessagesFileName),'FinalHtml');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadOPTsessionfileMenu = uimenu(app.Menu);
            app.LoadOPTsessionfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadOPTsessionfileMenuSelected, true);
            app.LoadOPTsessionfileMenu.Accelerator = 'L';
            app.LoadOPTsessionfileMenu.Text = 'Load .OPTsession file...';
            app.SaveasOPTsessionfileMenu = uimenu(app.Menu);
            app.SaveasOPTsessionfileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasOPTsessionfileMenuSelected, true);
            app.SaveasOPTsessionfileMenu.Accelerator = 'S';
            app.SaveasOPTsessionfileMenu.Text = 'Save as .OPTsession file...';
            app.SaveasMenu = uimenu(app.Menu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as...';
            app.ClearallfieldsMenu = uimenu(app.Menu);
            app.ClearallfieldsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearallfieldsMenuSelected, true);
            app.ClearallfieldsMenu.Text = 'Clear all fields';
            app.QuitappMenu = uimenu(app.Menu);
            app.QuitappMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitappMenuSelected, true);
            app.QuitappMenu.Text = 'Quit app';
            app.ResultsMenu = uimenu(app.UIFigure);
            app.ResultsMenu.Text = 'Results';
            app.ClearandloadsavedmessagesMenu = uimenu(app.ResultsMenu);
            app.ClearandloadsavedmessagesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearandloadsavedmessagesMenuSelected, true);
            app.ClearandloadsavedmessagesMenu.Text = 'Clear and load saved messages';
            app.SavedisplayedmessagesMenu = uimenu(app.ResultsMenu);
            app.SavedisplayedmessagesMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedisplayedmessagesMenuSelected, true);
            app.SavedisplayedmessagesMenu.Text = 'Save displayed messages';
            app.LoadsavedresultsMenu = uimenu(app.ResultsMenu);
            app.LoadsavedresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsavedresultsMenuSelected, true);
            app.LoadsavedresultsMenu.Text = 'Load saved results';
            app.ClearresultsMenu = uimenu(app.ResultsMenu);
            app.ClearresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearresultsMenuSelected, true);
            app.ClearresultsMenu.Text = 'Clear results';
            app.SavedisplayedresultsMenu = uimenu(app.ResultsMenu);
            app.SavedisplayedresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedisplayedresultsMenuSelected, true);
            app.SavedisplayedresultsMenu.Text = 'Save displayed results';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationwithexamplesMenu = uimenu(app.HelpMenu);
            app.DocumentationwithexamplesMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationwithexamplesMenuSelected, true);
            app.DocumentationwithexamplesMenu.Text = 'Documentation with examples';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralPropellantDataTab = uitab(app.TabGroup);
            app.GeneralPropellantDataTab.Title = 'General & Propellant Data';
            app.GeneralPanel = uipanel(app.GeneralPropellantDataTab);
            app.GeneralPanel.Title = 'General';
            app.SimulationstitleLabel = uilabel(app.GeneralPanel);
            app.SimulationstitleLabel.Tooltip = {'it is added in subfolder''s names'};
            app.SimulationstitleLabel.Text = 'Session''s title';
            app.session_title = uieditfield(app.GeneralPanel, 'text');
            app.session_title.Tooltip = {'it is added in subfolder''s names'};
            app.Factorforlanding_pointsLabel = uilabel(app.GeneralPanel);
            app.Factorforlanding_pointsLabel.Tooltip = {'a horizontal scaling factor used for landing point'};
            app.Factorforlanding_pointsLabel.Text = 'Factor for landing points [1]';
            app.factor_landing_points = uieditfield(app.GeneralPanel, 'numeric');
            app.factor_landing_points.Tooltip = {'a horizontal scaling factor used for landing point'};
            app.FactorforaltitudeLabel = uilabel(app.GeneralPanel);
            app.FactorforaltitudeLabel.Tooltip = {'a vertical scaling factor used for altitude'};
            app.FactorforaltitudeLabel.Text = 'Factor for altitude [1]';
            app.factor_altitude = uieditfield(app.GeneralPanel, 'numeric');
            app.factor_altitude.Tooltip = {'a vertical scaling factor used for altitude'};
            app.ExpectedAccuracyLabel = uilabel(app.GeneralPanel);
            app.ExpectedAccuracyLabel.Tooltip = {'it is defined as decimal fraction of grain''s diameters'};
            app.ExpectedAccuracyLabel.Text = 'Expected relative accuracy [1]';
            app.expected_relative_accuracy = uieditfield(app.GeneralPanel, 'numeric');
            app.expected_relative_accuracy.Tooltip = {'it is defined as decimal fraction of grain''s diameters'};
            app.FuelDataPanel = uipanel(app.GeneralPropellantDataTab);
            app.FuelDataPanel.Enable = 'off';
            app.FuelDataPanel.Title = 'Propellant data (unapproachable in CEAM)';
            app.PropellantdensityLabel = uilabel(app.FuelDataPanel);
            app.PropellantdensityLabel.Tooltip = {'density of the propellant (all reactants)'};
            app.PropellantdensityLabel.Text = 'Propellant density [kg/m^3]';
            app.propellant_density = uieditfield(app.FuelDataPanel, 'numeric');
            app.propellant_density.Enable = 'off';
            app.propellant_density.Tooltip = {'density of the propellant (all reactants)'};
            app.EurncoefficientLabel = uilabel(app.FuelDataPanel);
            app.EurncoefficientLabel.Tooltip = {'the linear coefficient a in burn equation (r_dot = a⋅p^n)'};
            app.EurncoefficientLabel.Text = 'Burn linear coefficient [mm/s]';
            app.burn_coefficient = uieditfield(app.FuelDataPanel, 'numeric');
            app.burn_coefficient.Enable = 'off';
            app.burn_coefficient.Tooltip = {'the linear coefficient a in burn equation (r_dot = a⋅p^n)'};
            app.Burn_expoLabel = uilabel(app.FuelDataPanel);
            app.Burn_expoLabel.Tooltip = {'the exponent n in burn equation (r_dot = a⋅p^n), where pressure p is described in MPa'};
            app.Burn_expoLabel.Text = 'Burn exponent [1]';
            app.burn_expo = uieditfield(app.FuelDataPanel, 'numeric');
            app.burn_expo.Enable = 'off';
            app.burn_expo.Tooltip = {'the exponent n in burn equation (r_dot = a⋅p^n), where pressure p is described in MPa'};
            app.C_prLabel = uilabel(app.FuelDataPanel);
            app.C_prLabel.Tooltip = {'the specific heat of propellant '};
            app.C_prLabel.Text = 'Heat capacity of propellant [J/(kg⋅K)]';
            app.C_pr = uieditfield(app.FuelDataPanel, 'numeric');
            app.C_pr.Enable = 'off';
            app.C_pr.Tooltip = {'the specific heat of propellant '};
            app.beta1Label = uilabel(app.FuelDataPanel);
            app.beta1Label.Tooltip = {'the erosive constant'};
            app.beta1Label.Text = 'β [1]';
            app.beta = uieditfield(app.FuelDataPanel, 'numeric');
            app.beta.Enable = 'off';
            app.beta.Tooltip = {'the erosive constant'};
            app.Label_blocking_info_1 = uilabel(app.FuelDataPanel);
            app.Label_blocking_info_1.Text = '''Propellant Data'' panel is blocked if check box ''boolean_CEAM_data_usage'' in tab ''RPAT & RFS'' is unchecked. Then, fuel data is taken from RPAT input.';
            app.RunButton_1 = uibutton(app.GeneralPropellantDataTab, 'push');
            app.RunButton_1.ButtonPushedFcn = createCallbackFcn(app, @RunButton_1Pushed, true);
            app.RunButton_1.Text = 'Run';
            app.GeometryTypesTab = uitab(app.TabGroup);
            app.GeometryTypesTab.Title = 'Geometry Types';
            app.TabGroup2 = uitabgroup(app.GeometryTypesTab);
            app.TubeTab = uitab(app.TabGroup2);
            app.TubeTab.Title = 'Tube';
            app.diameterinnerLabel = uilabel(app.TubeTab);
            app.diameterinnerLabel.Tooltip = {'inner dimention of the grain perpendicular to its symmetry axis'};
            app.diameterinnerLabel.Text = 'Diameter inner [mm]';
            app.diameter_inner_1 = uieditfield(app.TubeTab, 'numeric');
            app.diameter_inner_1.Tooltip = {'inner dimention of the grain perpendicular to its symmetry axis'};
            app.OptimiseddimensionDropDownLabel = uilabel(app.TubeTab);
            app.OptimiseddimensionDropDownLabel.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDownLabel.Text = 'Optimised dimension';
            app.OptimiseddimensionDropDown = uidropdown(app.TubeTab);
            app.OptimiseddimensionDropDown.Items = {'diameter_inner'};
            app.OptimiseddimensionDropDown.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDown.Value = 'diameter_inner';
            app.ViewgeometryCheckBox = uicheckbox(app.TubeTab);
            app.ViewgeometryCheckBox.Tooltip = {'if checked, the geometry will be shown on new figure after its generation'};
            app.ViewgeometryCheckBox.Text = 'View geometry';
            app.ViewgeometryCheckBox.Value = true;
            app.SaveSTLfileCheckBox = uicheckbox(app.TubeTab);
            app.SaveSTLfileCheckBox.Tooltip = {'if checked, the geometry will be in .stl file in subfolder for specific iteration for choosen type of geometry'};
            app.SaveSTLfileCheckBox.Text = 'Save STL file';
            app.SaveSTLfileCheckBox.Value = true;
            app.AddtoTableofgeometriesButton_1 = uibutton(app.TubeTab, 'push');
            app.AddtoTableofgeometriesButton_1.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableofgeometriesButton_1Pushed, true);
            app.AddtoTableofgeometriesButton_1.Tooltip = {'add data from ''Tube'' tab to ''Table of geometries'''};
            app.AddtoTableofgeometriesButton_1.Text = 'Add to Table of geometries';
            app.FilenameLabel_2 = uilabel(app.TubeTab);
            app.FilenameLabel_2.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.FilenameLabel_2.Text = 'Filename';
            app.filename_1 = uieditfield(app.TubeTab, 'text');
            app.filename_1.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.lengthofcylinderLabel = uilabel(app.TubeTab);
            app.lengthofcylinderLabel.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.lengthofcylinderLabel.Text = 'Length of cylinder [mm]';
            app.length_cyl_1 = uieditfield(app.TubeTab, 'numeric');
            app.length_cyl_1.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.diameterouterLabel = uilabel(app.TubeTab);
            app.diameterouterLabel.Tooltip = {'outer dimention of the grain perpendicular to its symmetry axis'};
            app.diameterouterLabel.Text = 'Diameter outer [mm]';
            app.diameter_outer_1 = uieditfield(app.TubeTab, 'numeric');
            app.diameter_outer_1.Tooltip = {'outer dimention of the grain perpendicular to its symmetry axis'};
            app.ResolutionLabel = uilabel(app.TubeTab);
            app.ResolutionLabel.Tooltip = {'number of points on every edge in the grain'};
            app.ResolutionLabel.Text = 'Resolution';
            app.resolution_1 = uieditfield(app.TubeTab, 'numeric');
            app.resolution_1.Tooltip = {'number of points on every edge in the grain'};
            app.transparencyofsurfacesLabel = uilabel(app.TubeTab);
            app.transparencyofsurfacesLabel.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry'};
            app.transparencyofsurfacesLabel.Text = 'Transparency of surfaces';
            app.transparency_surfaces_1 = uieditfield(app.TubeTab, 'numeric');
            app.transparency_surfaces_1.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry; use values from range [0,1]'};
            app.scale_normals_1 = uieditfield(app.TubeTab, 'numeric');
            app.scale_normals_1.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.scaleofnormalsLabel = uilabel(app.TubeTab);
            app.scaleofnormalsLabel.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.scaleofnormalsLabel.Text = 'Scale factor for normals';
            app.DeletefromTableofgeometriesButton = uibutton(app.TubeTab, 'push');
            app.DeletefromTableofgeometriesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletefromTableofgeometriesButtonPushed, true);
            app.DeletefromTableofgeometriesButton.Tooltip = {'to delete a row in ''Table of geometries'' click on one of its cells and later on this button'};
            app.DeletefromTableofgeometriesButton.Text = 'Delete from Table of geometries';
            app.MultiChannelTab = uitab(app.TabGroup2);
            app.MultiChannelTab.Tooltip = {''};
            app.MultiChannelTab.Title = 'Multi Channel';
            app.ChannelsdiameterLabel = uilabel(app.MultiChannelTab);
            app.ChannelsdiameterLabel.Tooltip = {'diameter of identical channels on the ring defined field below'};
            app.ChannelsdiameterLabel.Text = 'Channel''s diameter [mm]';
            app.channel_diameter_2 = uieditfield(app.MultiChannelTab, 'numeric');
            app.channel_diameter_2.Tooltip = {'diameter of identical channels on the ring defined field below'};
            app.NumberofchannelsSpinnerLabel = uilabel(app.MultiChannelTab);
            app.NumberofchannelsSpinnerLabel.Tooltip = {'number of identical channels on the ring defined two fields below'};
            app.NumberofchannelsSpinnerLabel.Text = 'Number of channels';
            app.NumberofchannelsSpinner = uispinner(app.MultiChannelTab);
            app.NumberofchannelsSpinner.Tooltip = {'number of identical channels on the ring defined two fields below'};
            app.RingchannelsdiameterLabel = uilabel(app.MultiChannelTab);
            app.RingchannelsdiameterLabel.Tooltip = {'diameter of ring which is base to position identical channels with constant angular spacing'};
            app.RingchannelsdiameterLabel.Text = {'Ring channel''s diameter'; '[mm]'};
            app.channel_ring_diameter_2 = uieditfield(app.MultiChannelTab, 'numeric');
            app.channel_ring_diameter_2.Tooltip = {'diameter of ring which is base to position identical channels with constant angular spacing'};
            app.OptimiseddimensionDropDown_2Label = uilabel(app.MultiChannelTab);
            app.OptimiseddimensionDropDown_2Label.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDown_2Label.Text = 'Optimised dimension';
            app.OptimiseddimensionDropDown_2 = uidropdown(app.MultiChannelTab);
            app.OptimiseddimensionDropDown_2.Items = {'channel_ring_diameter'};
            app.OptimiseddimensionDropDown_2.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDown_2.Value = 'channel_ring_diameter';
            app.ViewgeometryCheckBox_2 = uicheckbox(app.MultiChannelTab);
            app.ViewgeometryCheckBox_2.Tooltip = {'if checked, the geometry will be shown on new figure after its generation'};
            app.ViewgeometryCheckBox_2.Text = 'View geometry';
            app.ViewgeometryCheckBox_2.Value = true;
            app.SaveSTLfileCheckBox_2 = uicheckbox(app.MultiChannelTab);
            app.SaveSTLfileCheckBox_2.Tooltip = {'if checked, the geometry will be in .stl file in subfolder for specific iteration for choosen type of geometry'};
            app.SaveSTLfileCheckBox_2.Text = 'Save STL file';
            app.SaveSTLfileCheckBox_2.Value = true;
            app.FilenameLabel = uilabel(app.MultiChannelTab);
            app.FilenameLabel.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.FilenameLabel.Text = 'Filename';
            app.filename_2 = uieditfield(app.MultiChannelTab, 'text');
            app.filename_2.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.lengthofcylinderLabel_2 = uilabel(app.MultiChannelTab);
            app.lengthofcylinderLabel_2.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.lengthofcylinderLabel_2.Text = 'Length of cylinder [mm]';
            app.length_cyl_2 = uieditfield(app.MultiChannelTab, 'numeric');
            app.length_cyl_2.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.DiameterouterLabel = uilabel(app.MultiChannelTab);
            app.DiameterouterLabel.Tooltip = {'outer dimention of the grain perpendicular to its symmetry axis'};
            app.DiameterouterLabel.Text = 'Diameter outer [mm]';
            app.diameter_outer_2 = uieditfield(app.MultiChannelTab, 'numeric');
            app.diameter_outer_2.Tooltip = {'dimention of the grain perpendicular to its symmetry axis'};
            app.ResolutionLabel_2 = uilabel(app.MultiChannelTab);
            app.ResolutionLabel_2.Tooltip = {'number of points on every edge in the grain'};
            app.ResolutionLabel_2.Text = 'Resolution';
            app.resolution_2 = uieditfield(app.MultiChannelTab, 'numeric');
            app.resolution_2.Tooltip = {'number of points on every edge in the grain'};
            app.TransparencyofsurfacesLabel = uilabel(app.MultiChannelTab);
            app.TransparencyofsurfacesLabel.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry'};
            app.TransparencyofsurfacesLabel.Text = 'Transparency of surfaces';
            app.transparency_surfaces_2 = uieditfield(app.MultiChannelTab, 'numeric');
            app.transparency_surfaces_2.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry'};
            app.scale_normals_2 = uieditfield(app.MultiChannelTab, 'numeric');
            app.scale_normals_2.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.ScalefactorfornormalsLabel = uilabel(app.MultiChannelTab);
            app.ScalefactorfornormalsLabel.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.ScalefactorfornormalsLabel.Text = 'Scale factor for normals';
            app.AddtoTableofgeometriesButton_2 = uibutton(app.MultiChannelTab, 'push');
            app.AddtoTableofgeometriesButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableofgeometriesButton_2Pushed, true);
            app.AddtoTableofgeometriesButton_2.Tooltip = {'add data from ''Multi Channel'' tab to ''Table of geometries'''};
            app.AddtoTableofgeometriesButton_2.Text = 'Add to Table of geometries';
            app.DeletefromTableofgeometriesButton_2 = uibutton(app.MultiChannelTab, 'push');
            app.DeletefromTableofgeometriesButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeletefromTableofgeometriesButtonPushed, true);
            app.DeletefromTableofgeometriesButton_2.Tooltip = {'to delete a row in ''Table of geometries'' click on one of its cells and later on this button'};
            app.DeletefromTableofgeometriesButton_2.Text = 'Delete from Table of geometries';
            app.StarTab = uitab(app.TabGroup2);
            app.StarTab.Tooltip = {''};
            app.StarTab.Title = 'Star';
            app.OptimiseddimensionDropDown_3Label = uilabel(app.StarTab);
            app.OptimiseddimensionDropDown_3Label.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDown_3Label.Text = 'Optimised dimension';
            app.OptimiseddimensionDropDown_3 = uidropdown(app.StarTab);
            app.OptimiseddimensionDropDown_3.Items = {'star_outer_diameter'};
            app.OptimiseddimensionDropDown_3.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDown_3.Value = 'star_outer_diameter';
            app.starouterdiameterLabel = uilabel(app.StarTab);
            app.starouterdiameterLabel.Tooltip = {'diameter of a circle circumscribed on the geometric star'};
            app.starouterdiameterLabel.Text = 'Star''s outer diameter [mm]';
            app.star_outer_diameter_3 = uieditfield(app.StarTab, 'numeric');
            app.star_outer_diameter_3.Tooltip = {'diameter of a circle circumscribed on the geometric star'};
            app.NumberofstarsarmsSpinnerLabel = uilabel(app.StarTab);
            app.NumberofstarsarmsSpinnerLabel.Tooltip = {'number of arms in geometric star'};
            app.NumberofstarsarmsSpinnerLabel.Text = 'Number of star''s arms';
            app.NumberofstarsarmsSpinner = uispinner(app.StarTab);
            app.NumberofstarsarmsSpinner.Tooltip = {'number of arms in geometric star'};
            app.stararmangleLabel = uilabel(app.StarTab);
            app.stararmangleLabel.Tooltip = {'angle between two edges of on arm in the geometric star'};
            app.stararmangleLabel.Text = 'Star''s arm angle [°]';
            app.star_arm_angle_3 = uieditfield(app.StarTab, 'numeric');
            app.star_arm_angle_3.Tooltip = {'angle between two edges of on arm in the geometric star'};
            app.ViewgeometryCheckBox_3 = uicheckbox(app.StarTab);
            app.ViewgeometryCheckBox_3.Tooltip = {'if checked, the geometry will be shown on new figure after its generation'};
            app.ViewgeometryCheckBox_3.Text = 'View geometry';
            app.ViewgeometryCheckBox_3.Value = true;
            app.SaveSTLfileCheckBox_3 = uicheckbox(app.StarTab);
            app.SaveSTLfileCheckBox_3.Tooltip = {'if checked, the geometry will be in .stl file in subfolder for specific iteration for choosen type of geometry'};
            app.SaveSTLfileCheckBox_3.Text = 'Save STL file';
            app.SaveSTLfileCheckBox_3.Value = true;
            app.FilenameLabel_3 = uilabel(app.StarTab);
            app.FilenameLabel_3.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.FilenameLabel_3.Text = 'Filename';
            app.filename_3 = uieditfield(app.StarTab, 'text');
            app.filename_3.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.lengthofcylinderLabel_3 = uilabel(app.StarTab);
            app.lengthofcylinderLabel_3.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.lengthofcylinderLabel_3.Text = 'Length of cylinder [mm]';
            app.length_cyl_3 = uieditfield(app.StarTab, 'numeric');
            app.length_cyl_3.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.DiameterouterLabel_2 = uilabel(app.StarTab);
            app.DiameterouterLabel_2.Tooltip = {'outer dimention of the grain perpendicular to its symmetry axis'};
            app.DiameterouterLabel_2.Text = 'Diameter outer [mm]';
            app.diameter_outer_3 = uieditfield(app.StarTab, 'numeric');
            app.diameter_outer_3.Tooltip = {'dimention of the grain perpendicular to its symmetry axis'};
            app.ResolutionLabel_4 = uilabel(app.StarTab);
            app.ResolutionLabel_4.Tooltip = {'number of points on every edge in the grain'};
            app.ResolutionLabel_4.Text = 'Resolution';
            app.resolution_3 = uieditfield(app.StarTab, 'numeric');
            app.resolution_3.Tooltip = {'number of points on every edge in the grain'};
            app.TransparencyofsurfacesLabel_2 = uilabel(app.StarTab);
            app.TransparencyofsurfacesLabel_2.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry'};
            app.TransparencyofsurfacesLabel_2.Text = 'Transparency of surfaces';
            app.transparency_surfaces_3 = uieditfield(app.StarTab, 'numeric');
            app.transparency_surfaces_3.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry'};
            app.scale_normals_3 = uieditfield(app.StarTab, 'numeric');
            app.scale_normals_3.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.ScalefactorfornormalsLabel_2 = uilabel(app.StarTab);
            app.ScalefactorfornormalsLabel_2.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.ScalefactorfornormalsLabel_2.Text = 'Scale factor for normals';
            app.AddtoTableofgeometriesButton_3 = uibutton(app.StarTab, 'push');
            app.AddtoTableofgeometriesButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableofgeometriesButton_3Pushed, true);
            app.AddtoTableofgeometriesButton_3.Tooltip = {'add data from ''Star'' tab to ''Table of geometries'''};
            app.AddtoTableofgeometriesButton_3.Text = 'Add to Table of geometries';
            app.DeletefromTableofgeometriesButton_3 = uibutton(app.StarTab, 'push');
            app.DeletefromTableofgeometriesButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeletefromTableofgeometriesButtonPushed, true);
            app.DeletefromTableofgeometriesButton_3.Tooltip = {'to delete a row in ''Table of geometries'' click on one of its cells and later on this button'};
            app.DeletefromTableofgeometriesButton_3.Text = 'Delete from Table of geometries';
            app.FunctionChannelTab = uitab(app.TabGroup2);
            app.FunctionChannelTab.Tooltip = {''};
            app.FunctionChannelTab.Title = 'Function Channel';
            app.ViewgeometryCheckBox_4 = uicheckbox(app.FunctionChannelTab);
            app.ViewgeometryCheckBox_4.Tooltip = {'if checked, the geometry will be shown on new figure after its generation'};
            app.ViewgeometryCheckBox_4.Text = 'View geometry';
            app.ViewgeometryCheckBox_4.Value = true;
            app.FilenameLabel_4 = uilabel(app.FunctionChannelTab);
            app.FilenameLabel_4.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.FilenameLabel_4.Text = 'Filename';
            app.filename_4 = uieditfield(app.FunctionChannelTab, 'text');
            app.filename_4.Tooltip = {'name of generated .stl file, used also as name of subfolder with results for this type of grain''s geometry'};
            app.lengthofcylinderLabel_4 = uilabel(app.FunctionChannelTab);
            app.lengthofcylinderLabel_4.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.lengthofcylinderLabel_4.Text = 'Length of cylinder [mm]';
            app.length_cyl_4 = uieditfield(app.FunctionChannelTab, 'numeric');
            app.length_cyl_4.Tooltip = {'dimention of the grain along its symmetry axis'};
            app.DiameterouterLabel_3 = uilabel(app.FunctionChannelTab);
            app.DiameterouterLabel_3.Tooltip = {'outer dimention of the grain perpendicular to its symmetry axis'};
            app.DiameterouterLabel_3.Text = 'Diameter outer [mm]';
            app.diameter_outer_4 = uieditfield(app.FunctionChannelTab, 'numeric');
            app.diameter_outer_4.Tooltip = {'dimention of the grain perpendicular to its symmetry axis'};
            app.ResolutionLabel_3 = uilabel(app.FunctionChannelTab);
            app.ResolutionLabel_3.Tooltip = {'number of points on every edge in the grain'};
            app.ResolutionLabel_3.Text = 'Resolution';
            app.resolution_4 = uieditfield(app.FunctionChannelTab, 'numeric');
            app.resolution_4.Tooltip = {'number of points on every edge in the grain'};
            app.TransparencyofsurfacesLabel_3 = uilabel(app.FunctionChannelTab);
            app.TransparencyofsurfacesLabel_3.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry'};
            app.TransparencyofsurfacesLabel_3.Text = 'Transparency of surfaces';
            app.transparency_surfaces_4 = uieditfield(app.FunctionChannelTab, 'numeric');
            app.transparency_surfaces_4.Tooltip = {'transparency of grain''s outer surfaces on the figure which shows geometry'};
            app.scale_normals_4 = uieditfield(app.FunctionChannelTab, 'numeric');
            app.scale_normals_4.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.ScalefactorfornormalsLabel_3 = uilabel(app.FunctionChannelTab);
            app.ScalefactorfornormalsLabel_3.Tooltip = {'scale factor defines length of normals (useful to check if geometry is defined properly) on the figure which shows geometry; suggested value is 1 to make normals visible and 0 to hide them'};
            app.ScalefactorfornormalsLabel_3.Text = 'Scale factor for normals';
            app.OptimiseddimensionDropDown_4Label = uilabel(app.FunctionChannelTab);
            app.OptimiseddimensionDropDown_4Label.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDown_4Label.Text = 'Optimised dimension';
            app.OptimiseddimensionDropDown_4 = uidropdown(app.FunctionChannelTab);
            app.OptimiseddimensionDropDown_4.Items = {'radial_function'};
            app.OptimiseddimensionDropDown_4.Tooltip = {'dimention which value is changed through all iterations with use of bisection'};
            app.OptimiseddimensionDropDown_4.Value = 'radial_function';
            app.RadialfunctionLabel = uilabel(app.FunctionChannelTab);
            app.RadialfunctionLabel.Tooltip = {'function of angle (in radians) in radial coordinate system that dercribes shape of inner surface (as distance in milimeters from symmetry axis)'};
            app.RadialfunctionLabel.Text = {'Radial function'; '[mm=f(rad)]'};
            app.radial_function_4 = uieditfield(app.FunctionChannelTab, 'text');
            app.radial_function_4.Tooltip = {'function of angle (in radians) in radial coordinate system that dercribes shape of inner surface (as distance in milimeters from symmetry axis)'};
            app.SaveSTLfileCheckBox_4 = uicheckbox(app.FunctionChannelTab);
            app.SaveSTLfileCheckBox_4.Tooltip = {'if checked, the geometry will be in .stl file in subfolder for specific iteration for choosen type of geometry'};
            app.SaveSTLfileCheckBox_4.Text = 'Save STL file';
            app.SaveSTLfileCheckBox_4.Value = true;
            app.AddtoTableofgeometriesButton_4 = uibutton(app.FunctionChannelTab, 'push');
            app.AddtoTableofgeometriesButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableofgeometriesButton_4Pushed, true);
            app.AddtoTableofgeometriesButton_4.Tooltip = {'add data from ''Function Channel'' tab to ''Table of geometries'''};
            app.AddtoTableofgeometriesButton_4.Text = 'Add to Table of geometries';
            app.DeletefromTableofgeometriesButton_4 = uibutton(app.FunctionChannelTab, 'push');
            app.DeletefromTableofgeometriesButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeletefromTableofgeometriesButtonPushed, true);
            app.DeletefromTableofgeometriesButton_4.Tooltip = {'to delete a row in ''Table of geometries'' click on one of its cells and later on this button'};
            app.DeletefromTableofgeometriesButton_4.Text = 'Delete from Table of geometries';
            app.TableofgeometriesPanel = uipanel(app.GeometryTypesTab);
            app.TableofgeometriesPanel.Title = 'Table of geometries';
            app.UITableGeometries = uitable(app.TableofgeometriesPanel);
            app.UITableGeometries.ColumnName = {'type'; 'filename'; 'length_cyl'; 'diameter_outer'; 'resolution'; 'viewGeometry'; 'transparency_surfaces'; 'scale_normals'; 'optimised_dimension'; 'saveSTL'; 'diameter_inner'; 'channel_number'; 'channel_diameter'; 'channel_ring_diameter'; 'star_arms_number'; 'star_outer_diameter'; 'star_arm_angle'; 'radial_function'};
            app.UITableGeometries.RowName = {};
            app.UITableGeometries.CellSelectionCallback = createCallbackFcn(app, @UITableGeometriesCellSelection, true);
            app.UITableGeometries.ButtonDownFcn = createCallbackFcn(app, @UITableGeometriesCellSelection, true);
            app.RunButton_2 = uibutton(app.GeometryTypesTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_1Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.CEAMTab = uitab(app.TabGroup);
            app.CEAMTab.Title = 'CEAM';
            app.TableofreactantsforCEAMPanel = uipanel(app.CEAMTab);
            app.TableofreactantsforCEAMPanel.Enable = 'off';
            app.TableofreactantsforCEAMPanel.Title = 'Table of reactants for CEAM';
            app.UITableReactants = uitable(app.TableofreactantsforCEAMPanel);
            app.UITableReactants.ColumnName = {'names'; 'weight_percent'; 'formula'; 'enthalpy'; 'enthalpy_unit'; 'density'; 'density_unit'};
            app.UITableReactants.RowName = {};
            app.UITableReactants.CellSelectionCallback = createCallbackFcn(app, @UITableReactantsCellSelection, true);
            app.UITableReactants.Enable = 'off';
            app.NameLabel = uilabel(app.CEAMTab);
            app.NameLabel.Enable = 'off';
            app.NameLabel.Tooltip = {'name of reactant'};
            app.NameLabel.Text = 'Name';
            app.name = uieditfield(app.CEAMTab, 'text');
            app.name.Enable = 'off';
            app.name.Tooltip = {'name of reactant'};
            app.WeightpercentLabel = uilabel(app.CEAMTab);
            app.WeightpercentLabel.Enable = 'off';
            app.WeightpercentLabel.Tooltip = {'weight percent of reactant in whole propellant'};
            app.WeightpercentLabel.Text = 'Weight percent [%]';
            app.weight_percent = uieditfield(app.CEAMTab, 'numeric');
            app.weight_percent.Enable = 'off';
            app.weight_percent.Tooltip = {'weight percent of reactant in whole propellant'};
            app.EnthalpyunitLabel = uilabel(app.CEAMTab);
            app.EnthalpyunitLabel.Enable = 'off';
            app.EnthalpyunitLabel.Tooltip = {'unit of enthalpy in which value was written the field above, CEAM notation has to be used'};
            app.EnthalpyunitLabel.Text = 'Enthalpy unit';
            app.enthalpy_unit = uieditfield(app.CEAMTab, 'text');
            app.enthalpy_unit.Enable = 'off';
            app.enthalpy_unit.Tooltip = {'unit of enthalpy in which value was written the field above, CEAM notation has to be used'};
            app.EnthalpyLabel = uilabel(app.CEAMTab);
            app.EnthalpyLabel.Enable = 'off';
            app.EnthalpyLabel.Tooltip = {'enthalpy of reactant'};
            app.EnthalpyLabel.Text = 'Enthalpy';
            app.enthalpy = uieditfield(app.CEAMTab, 'numeric');
            app.enthalpy.Enable = 'off';
            app.enthalpy.Tooltip = {'enthalpy of reactant'};
            app.DensityunitLabel = uilabel(app.CEAMTab);
            app.DensityunitLabel.Enable = 'off';
            app.DensityunitLabel.Tooltip = {'unit of density in which value was written the field above, CEAM notation has to be used'};
            app.DensityunitLabel.Text = 'Density unit';
            app.density_unit = uieditfield(app.CEAMTab, 'text');
            app.density_unit.Enable = 'off';
            app.density_unit.Tooltip = {'unit of density in which value was written the field above, CEAM notation has to be used'};
            app.DensityLabel = uilabel(app.CEAMTab);
            app.DensityLabel.Enable = 'off';
            app.DensityLabel.Tooltip = {'density of reactant'};
            app.DensityLabel.Text = 'Density';
            app.density = uieditfield(app.CEAMTab, 'numeric');
            app.density.Enable = 'off';
            app.density.Tooltip = {'density of reactant'};
            app.AddtoTableofreactantsforCEAMButton = uibutton(app.CEAMTab, 'push');
            app.AddtoTableofreactantsforCEAMButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableofreactantsforCEAMButtonPushed, true);
            app.AddtoTableofreactantsforCEAMButton.Enable = 'off';
            app.AddtoTableofreactantsforCEAMButton.Tooltip = {'add data from upper half of ''CEAM'' tab to ''Table of reactants for CEAM'''};
            app.AddtoTableofreactantsforCEAMButton.Text = 'Add to Table of reactants for CEAM';
            app.FormulaPanel = uipanel(app.CEAMTab);
            app.FormulaPanel.Enable = 'off';
            app.FormulaPanel.Title = 'Formula';
            app.UITableElements = uitable(app.FormulaPanel);
            app.UITableElements.ColumnName = {'Element'; 'Number of atoms'};
            app.UITableElements.RowName = {};
            app.UITableElements.CellSelectionCallback = createCallbackFcn(app, @UITableElementsCellSelection, true);
            app.UITableElements.Enable = 'off';
            app.AddtoTableofelementsButton = uibutton(app.FormulaPanel, 'push');
            app.AddtoTableofelementsButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableofelementsButtonPushed, true);
            app.AddtoTableofelementsButton.Enable = 'off';
            app.AddtoTableofelementsButton.Tooltip = {'add element and its number of atoms to ''Table of elements'''};
            app.AddtoTableofelementsButton.Text = 'Add to Table of elements';
            app.NumberofatomsSpinnerLabel = uilabel(app.FormulaPanel);
            app.NumberofatomsSpinnerLabel.Tooltip = {'set number of atoms in the reactant formula picked above to save it in ''Table of elements'''};
            app.NumberofatomsSpinnerLabel.Text = 'Number of atoms';
            app.NumberofatomsSpinner = uispinner(app.FormulaPanel);
            app.NumberofatomsSpinner.Enable = 'off';
            app.NumberofatomsSpinner.Tooltip = {'set number of atoms in the reactant formula picked above to save it in ''Table of elements'''};
            app.ElementDropDownLabel = uilabel(app.FormulaPanel);
            app.ElementDropDownLabel.Tooltip = {'pick an element from the reactant formula to add it to ''Table of elements'''};
            app.ElementDropDownLabel.Text = 'Element';
            app.ElementDropDown = uidropdown(app.FormulaPanel);
            app.ElementDropDown.Items = {'H', 'He', 'Li', 'Be', 'B', 'C', 'N', 'O', 'F', 'Ne', 'Na', 'Mg', 'Al', 'Si', 'P', 'S', 'Cl', 'Ar', 'K', 'Ca', 'Sc', 'Ti', 'V', 'Cr', 'Mn', 'Fe', 'Co', 'Ni', 'Cu', 'Zn', 'Ga', 'Ge', 'As', 'Se', 'Br', 'Kr', 'Rb', 'Sr', 'Y', 'Zr', 'Nb', 'Mo', 'Tc', 'Ru', 'Rh', 'Pd', 'Ag', 'Cd', 'In', 'Sn', 'Sb', 'Te', 'I', 'Xe', 'Cs', 'Ba', 'La', 'Ce', 'Pr', 'Nd', 'Pm', 'Sm', 'Eu', 'Gd', 'Tb', 'Dy', 'Ho', 'Er', 'Tm', 'Yb', 'Lu', 'Hf', 'Ta', 'W', 'Re', 'Os', 'Ir', 'Pt', 'Au', 'Hg', 'Tl', 'Pb', 'Bi', 'Po', 'At', 'Rn', 'Fr', 'Ra', 'Ac', 'Th', 'Pa', 'U', 'Np', 'Pu', 'Am', 'Cm', 'Bk', 'Cf', 'Es', 'Fm', 'Md', 'No', 'Lr', 'Rf', 'Db', 'Sg', 'Bh', 'Hs', 'Mt', 'Ds', 'Rg', 'Cn', 'Nh', 'Fl', 'Mc', 'Lv', 'Ts', 'Og'};
            app.ElementDropDown.Enable = 'off';
            app.ElementDropDown.Tooltip = {'pick an element from the reactant formula to add it to ''Table of elements'''};
            app.ElementDropDown.Value = 'H';
            app.DeletefromTableofelementsButton = uibutton(app.FormulaPanel, 'push');
            app.DeletefromTableofelementsButton.ButtonPushedFcn = createCallbackFcn(app, @DeletefromTableofelementsButtonPushed, true);
            app.DeletefromTableofelementsButton.Enable = 'off';
            app.DeletefromTableofelementsButton.Tooltip = {'to delete a row in ''Table of elements'' click on one of its cells and later on this button'};
            app.DeletefromTableofelementsButton.Text = 'Delete from Table of elements';
            app.Label_blocking_info_2 = uilabel(app.CEAMTab);
            app.Label_blocking_info_2.Text = '''CEAM'' tab is blocked if check box ''boolean_CEAM_data_usage'' in tab ''RPAT & RFS'' is unchecked. Then, fuel data is taken from RPAT input.';
            app.DeletefromTableofreactantsforCEAMButton = uibutton(app.CEAMTab, 'push');
            app.DeletefromTableofreactantsforCEAMButton.ButtonPushedFcn = createCallbackFcn(app, @DeletefromTableofreactantsforCEAMButtonPushed, true);
            app.DeletefromTableofreactantsforCEAMButton.Enable = 'off';
            app.DeletefromTableofreactantsforCEAMButton.Tooltip = {'to delete a row in ''Table of reactants for CEAM'' click on one of its cells and later on this button'};
            app.DeletefromTableofreactantsforCEAMButton.Text = 'Delete from Table of reactants for CEAM';
            app.RunButton_3 = uibutton(app.CEAMTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_1Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.RPATRFSTab = uitab(app.TabGroup);
            app.RPATRFSTab.Title = 'RPAT & RFS';
            app.RPATdata = uipanel(app.RPATRFSTab);
            app.RPATdata.Title = 'RPAT data';
            app.UsedatafromCEAMCheckBox = uicheckbox(app.RPATdata);
            app.UsedatafromCEAMCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsedatafromCEAMCheckBoxValueChanged, true);
            app.UsedatafromCEAMCheckBox.Tooltip = {'unlocks ''Propellant Data'' panel and ''CEAM'' tab; later data from those two place are used'};
            app.UsedatafromCEAMCheckBox.Text = 'Use data from CEAM';
            app.OriginalsessionfilepathLabel = uilabel(app.RPATdata);
            app.OriginalsessionfilepathLabel.Tooltip = {'original session filepath for RPAT which can be write down here manually or automatically by button on below left through a file explorer window'};
            app.OriginalsessionfilepathLabel.Text = 'Original session filepath';
            app.original_session_filepathRPAT = uieditfield(app.RPATdata, 'text');
            app.original_session_filepathRPAT.Tooltip = {'original session filepath for RPAT which can be write down here manually or automatically by button on below left through a file explorer window'};
            app.SelectRPATinputfileButton = uibutton(app.RPATdata, 'push');
            app.SelectRPATinputfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRPATinputfileButtonPushed, true);
            app.SelectRPATinputfileButton.Tooltip = {'opens file explorer window to pick up the original session filepath for RPAT and save its path automatically'};
            app.SelectRPATinputfileButton.Text = 'Select RPAT input file';
            app.CreateRPATinputfileinRPATsGUIButton = uibutton(app.RPATdata, 'push');
            app.CreateRPATinputfileinRPATsGUIButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRPATinputfileinRPATsGUIButtonPushed, true);
            app.CreateRPATinputfileinRPATsGUIButton.Tooltip = {'opens GUI for RPAT, where RPAT input could be created (it has to be saved there!)'};
            app.CreateRPATinputfileinRPATsGUIButton.Text = 'Create RPAT input file in RPAT''s GUI';
            app.RFSdataPanel = uipanel(app.RPATRFSTab);
            app.RFSdataPanel.Title = 'RFS data';
            app.UsecoordinatesfromsessionfileCheckBox = uicheckbox(app.RFSdataPanel);
            app.UsecoordinatesfromsessionfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsecoordinatesfromsessionfileCheckBoxValueChanged, true);
            app.UsecoordinatesfromsessionfileCheckBox.Tooltip = {'check this box if the latitude and the longitude from RFS session file should be used (if remained unchecked, fields on the right should be filled in) '};
            app.UsecoordinatesfromsessionfileCheckBox.Text = 'Use coordinates from session file';
            app.UseazimuthfromsessionfileCheckBox = uicheckbox(app.RFSdataPanel);
            app.UseazimuthfromsessionfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseazimuthfromsessionfileCheckBoxValueChanged, true);
            app.UseazimuthfromsessionfileCheckBox.Tooltip = {'check this box if the azimuth from RFS session file should be used (if remained unchecked, field on the right should be filled in) '};
            app.UseazimuthfromsessionfileCheckBox.Text = 'Use azimuth from session file';
            app.TraininggroudceilLabel = uilabel(app.RFSdataPanel);
            app.TraininggroudceilLabel.Tooltip = {'the highest altitude which could be reached on the picked training ground'};
            app.TraininggroudceilLabel.Text = 'Training groud ceil [m]';
            app.training_groud_ceil = uieditfield(app.RFSdataPanel, 'numeric');
            app.training_groud_ceil.Tooltip = {'the highest altitude which could be reached on the picked training ground'};
            app.OriginalsessionfilepathLabel_2 = uilabel(app.RFSdataPanel);
            app.OriginalsessionfilepathLabel_2.Tooltip = {'original session filepath for RFS which can be write down here manually or automatically by button on below left through a file explorer window'};
            app.OriginalsessionfilepathLabel_2.Text = 'Original session filepath';
            app.original_session_filepathRFS = uieditfield(app.RFSdataPanel, 'text');
            app.original_session_filepathRFS.Tooltip = {'original session filepath for RFS which can be write down here manually or automatically by button on below left through a file explorer window'};
            app.MaxwindspeedLabel = uilabel(app.RFSdataPanel);
            app.MaxwindspeedLabel.Tooltip = {'constant wind speed for whole wind profile (left value ''0'' here if wind profile would be defined through the field on the right)'};
            app.MaxwindspeedLabel.Text = 'Maximum wind speed [m/s]';
            app.max_wind_speed = uieditfield(app.RFSdataPanel, 'numeric');
            app.max_wind_speed.ValueChangedFcn = createCallbackFcn(app, @max_wind_speedValueChanged, true);
            app.max_wind_speed.Tooltip = {'constant wind speed for whole wind profile (left value ''0'' here if wind profile would be defined through the field on the right)'};
            app.TraininggroudfilepathLabel = uilabel(app.RFSdataPanel);
            app.TraininggroudfilepathLabel.Tooltip = {'filepath of text file with border of training ground which can be write down here automatically by button on below left through a file explorer window'};
            app.TraininggroudfilepathLabel.Text = 'Training groud filepath';
            app.training_groud_filepath = uieditfield(app.RFSdataPanel, 'text');
            app.training_groud_filepath.Tooltip = {'filepath of text file with border of training ground which can be write down here automatically by button on below left through a file explorer window'};
            app.CreateRFSsessionfileinRFSGUIButton = uibutton(app.RFSdataPanel, 'push');
            app.CreateRFSsessionfileinRFSGUIButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRFSsessionfileinRFSGUIButtonPushed, true);
            app.CreateRFSsessionfileinRFSGUIButton.Tooltip = {'opens GUI for RFS, where RFS input could be created (it has to be saved there!)'};
            app.CreateRFSsessionfileinRFSGUIButton.Text = 'Create .RFSsession file in RFS'' GUI';
            app.LatitudedegEditFieldLabel = uilabel(app.RFSdataPanel);
            app.LatitudedegEditFieldLabel.Tooltip = {'latitude of the launch pad (editable only if the box on left remains unchecked)'};
            app.LatitudedegEditFieldLabel.Text = 'Latitude [deg]';
            app.latitude = uieditfield(app.RFSdataPanel, 'numeric');
            app.latitude.Tooltip = {'latitude of the launch pad (editable only if the box on left remains unchecked)'};
            app.LongitudedegEditFieldLabel = uilabel(app.RFSdataPanel);
            app.LongitudedegEditFieldLabel.Tooltip = {'longitude of the launch pad (editable only if the box on left remains unchecked)'};
            app.LongitudedegEditFieldLabel.Text = 'Longitude [deg]';
            app.longitude = uieditfield(app.RFSdataPanel, 'numeric');
            app.longitude.Tooltip = {'longitude of the launch pad (editable only if the box on left remains unchecked)'};
            app.AzimuthdegEditFieldLabel = uilabel(app.RFSdataPanel);
            app.AzimuthdegEditFieldLabel.Tooltip = {'azimute of the launch pad (editable only if the box on left remains unchecked)'};
            app.AzimuthdegEditFieldLabel.Text = 'Azimuth [deg]';
            app.azimuth = uieditfield(app.RFSdataPanel, 'numeric');
            app.azimuth.Tooltip = {'azimute of the launch pad (editable only if the box on left remains unchecked)'};
            app.original_session_filepathEditField_2Label_2 = uilabel(app.RFSdataPanel);
            app.original_session_filepathEditField_2Label_2.Tooltip = {'filepath of text file with wind profile which can be write down here automatically by button on below left through a file explorer window (left it empty if constand wind would be defined through the field on the left)'};
            app.original_session_filepathEditField_2Label_2.Text = 'Wind profile filepath';
            app.wind_profile_filepath = uieditfield(app.RFSdataPanel, 'text');
            app.wind_profile_filepath.ValueChangedFcn = createCallbackFcn(app, @wind_profile_filepathValueChanged, true);
            app.wind_profile_filepath.Tooltip = {'filepath of text file with wind profile which can be write down here automatically by button on below left through a file explorer window (left it empty if constand wind would be defined through the field on the left)'};
            app.SelectRFSsessionButton = uibutton(app.RFSdataPanel, 'push');
            app.SelectRFSsessionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRFSsessionButtonPushed, true);
            app.SelectRFSsessionButton.Tooltip = {'opens file explorer window to pick up the original session filepath for RPAT and save its path automatically'};
            app.SelectRFSsessionButton.Text = 'Select .RFSsession ';
            app.SelecttraininggroundfileButton = uibutton(app.RFSdataPanel, 'push');
            app.SelecttraininggroundfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttraininggroundfileButtonPushed, true);
            app.SelecttraininggroundfileButton.Tooltip = {'opens file explorer window to pick up the original session filepath for RPAT and save its path automatically'};
            app.SelecttraininggroundfileButton.Text = 'Select training ground file';
            app.SelectwindprofilefileButton = uibutton(app.RFSdataPanel, 'push');
            app.SelectwindprofilefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectwindprofilefileButtonPushed, true);
            app.SelectwindprofilefileButton.Tooltip = {'opens file explorer window to pick up the original session filepath for RPAT and save its path automatically'};
            app.SelectwindprofilefileButton.Text = 'Select wind profile file';
            app.TraininggroudceilLabel_2 = uilabel(app.RFSdataPanel);
            app.TraininggroudceilLabel_2.Tooltip = {'the closest distance to the training ground''s border or the training ground''s ceil that could be reached'};
            app.TraininggroudceilLabel_2.Text = 'Margin [m]';
            app.margin = uieditfield(app.RFSdataPanel, 'numeric');
            app.margin.Tooltip = {'the closest distance to the training ground''s border or the training ground''s ceil that could be reached'};
            app.margin_strict = uicheckbox(app.RFSdataPanel);
            app.margin_strict.Tooltip = {'check this box to continue calculations until the margin requirement would be fulfilled'};
            app.margin_strict.Text = 'Strict margin';
            app.RunButton_4 = uibutton(app.RPATRFSTab, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_1Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.MessagesTab = uitab(app.TabGroup);
            app.MessagesTab.Title = 'Messages';
            app.HTML = uihtml(app.MessagesTab);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UITableResults = uitable(app.ResultsTab);
            app.UITableResults.ColumnName = {'Date and time'; 'Geometry type'; 'Name'; 'Optimised dimension'; 'Optimised dimension final value'; 'Final geometry path'; 'Final geoplot path'};
            app.UITableResults.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OPT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end